classdef LQG_FP7_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        IOcontrolPanel                  matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        ParametersetselectSwitch        matlab.ui.control.ToggleSwitch
        ParametersetselectSwitchLabel   matlab.ui.control.Label
        EnablefeedbackSwitch            matlab.ui.control.ToggleSwitch
        EnablefeedbackLabel             matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ConnectTab                      matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        ResetinternalstatesButton       matlab.ui.control.Button
        DemoModeSwitch                  matlab.ui.control.Switch
        DemomodenohardwareconnectedSwitchLabel  matlab.ui.control.Label
        RedPitayaIPAddressEditField     matlab.ui.control.EditField
        RedPitayaIPAddressEditFieldLabel  matlab.ui.control.Label
        ConnectStateButton              matlab.ui.control.StateButton
        DataAcquisitionTab              matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        ReservedRAMEditField            matlab.ui.control.NumericEditField
        ReservedRAMMBEditFieldLabel     matlab.ui.control.Label
        PSDaveragingEditField           matlab.ui.control.NumericEditField
        PSDaveragingEditFieldLabel      matlab.ui.control.Label
        RecordingDecimationEditField    matlab.ui.control.NumericEditField
        RecordingdecimationNx64nsLabel  matlab.ui.control.Label
        SignalSelectorUITable           matlab.ui.control.Table
        NumberofframestorecordEditField  matlab.ui.control.NumericEditField
        NumberofframestorecordEditFieldLabel  matlab.ui.control.Label
        SavematfileButton               matlab.ui.control.Button
        OutputfilepathEditField         matlab.ui.control.EditField
        OutputfilepathEditFieldLabel    matlab.ui.control.Label
        PlotStyleSwitch                 matlab.ui.control.Switch
        PlotStyleSwitchLabel            matlab.ui.control.Label
        RecorddataButton                matlab.ui.control.Button
        NumberofframestoplotEditField   matlab.ui.control.NumericEditField
        NumberofframestoplotEditFieldLabel  matlab.ui.control.Label
        DataAxes                        matlab.ui.control.UIAxes
        ModelconfigurationTab           matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        PushparametersButton            matlab.ui.control.Button
        SaveparametersButton            matlab.ui.control.Button
        ImportparametersButton          matlab.ui.control.Button
        LoadparametersButton            matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        Parameterset1Tab                matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        TabGroup3                       matlab.ui.container.TabGroup
        DetectionActuationTab           matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        ActuatorcouplingcoefficientsNVorkgmsVLabel  matlab.ui.control.Label
        MeasurementnoisevariancesV2HzLabel  matlab.ui.control.Label
        DetectioncalibrationcoefficientsVmorVsmLabel  matlab.ui.control.Label
        MeasnoisematrixUITable          matlab.ui.control.Table
        CNVUITable                      matlab.ui.control.Table
        CVmUITable                      matlab.ui.control.Table
        TuningvariablesTab              matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        InternalmodelformatDropDownLabel  matlab.ui.control.Label
        InternalmodelformatDropDown     matlab.ui.control.DropDown
        FbGainUITable                   matlab.ui.control.Table
        RelativeweighingofstatesinQ_LQRdiagonesstandardenergycostLabel  matlab.ui.control.Label
        FeedbackweighingmatrixsqrtR_LQR1VLabel  matlab.ui.control.Label
        RecalculateLQRButton            matlab.ui.control.Button
        RelativeStateWeightUITable      matlab.ui.control.Table
        UsePhiCheckBox                  matlab.ui.control.CheckBox
        UseZaxisCheckBox                matlab.ui.control.CheckBox
        UseYaxisCheckBox                matlab.ui.control.CheckBox
        UseXaxisCheckBox                matlab.ui.control.CheckBox
        FeedbackgainTab                 matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        Estimatedoutputvoltagestandarddeviation3VLabel  matlab.ui.control.Label
        KLQRmatrixentriesLabel          matlab.ui.control.Label
        FbStdUITable                    matlab.ui.control.Table
        KLQRdispUITable                 matlab.ui.control.Table
        IOcalibrationTab                matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        InputADCcalibrationLabel        matlab.ui.control.Label
        OutputDACcalibrationLabel       matlab.ui.control.Label
        ADCCalibUITable                 matlab.ui.control.Table
        DACCalibUITable                 matlab.ui.control.Table
        AdvancedTab                     matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        TargetstatestandarddeviationEditField  matlab.ui.control.NumericEditField
        TargetstatestandarddeviationLabel  matlab.ui.control.Label
        UndoTransformForPlotCheckBox    matlab.ui.control.CheckBox
        AdvancedTabLabel                matlab.ui.control.Label
        LimitTransformCheckBox          matlab.ui.control.CheckBox
        TransformStateSpaceCheckBox     matlab.ui.control.CheckBox
        ParticlemassEditField           matlab.ui.control.NumericEditField
        ParticlemassEditFieldLabel      matlab.ui.control.Label
        PhiprocessnoiseEditField        matlab.ui.control.NumericEditField
        PhiprocessnoiseEditFieldLabel   matlab.ui.control.Label
        ZprocessnoiseEditField          matlab.ui.control.NumericEditField
        ZprocessnoiseEditFieldLabel     matlab.ui.control.Label
        YprocessnoiseEditField          matlab.ui.control.NumericEditField
        YprocessnoiseEditFieldLabel     matlab.ui.control.Label
        XprocessnoiseEditField          matlab.ui.control.NumericEditField
        XprocessnoiseEditFieldLabel     matlab.ui.control.Label
        ZdampingEditField               matlab.ui.control.NumericEditField
        ZdampingEditFieldLabel          matlab.ui.control.Label
        YdampingEditField               matlab.ui.control.NumericEditField
        YdampingEditFieldLabel          matlab.ui.control.Label
        XdampingEditField               matlab.ui.control.NumericEditField
        XdampingEditFieldLabel          matlab.ui.control.Label
        PhiTimeConstEditField           matlab.ui.control.NumericEditField
        PhiTimeConstEditFieldLabel      matlab.ui.control.Label
        ZfrequencyEditField             matlab.ui.control.NumericEditField
        ZfrequencyEditFieldLabel        matlab.ui.control.Label
        YfrequencyEditField             matlab.ui.control.NumericEditField
        YfrequencyEditFieldLabel        matlab.ui.control.Label
        XfrequencyEditField             matlab.ui.control.NumericEditField
        XfrequencyEditFieldLabel        matlab.ui.control.Label
        Parameterset2Tab                matlab.ui.container.Tab
        GridLayout9_2                   matlab.ui.container.GridLayout
        TabGroup3_2                     matlab.ui.container.TabGroup
        DetectionActuationTab_2         matlab.ui.container.Tab
        GridLayout10_2                  matlab.ui.container.GridLayout
        ActuatorcouplingcoefficientsNVorkgmsVLabel_2  matlab.ui.control.Label
        MeasurementnoisevariancesV2HzLabel_2  matlab.ui.control.Label
        DetectioncalibrationcoefficientsVmorVsmLabel_2  matlab.ui.control.Label
        MeasnoisematrixUITable_2        matlab.ui.control.Table
        CNVUITable_2                    matlab.ui.control.Table
        CVmUITable_2                    matlab.ui.control.Table
        TuningvariablesTab_2            matlab.ui.container.Tab
        GridLayout11_2                  matlab.ui.container.GridLayout
        InternalmodelformatDropDown_2Label  matlab.ui.control.Label
        InternalmodelformatDropDown_2   matlab.ui.control.DropDown
        FbGainUITable_2                 matlab.ui.control.Table
        Label                           matlab.ui.control.Label
        FeedbackweighingmatrixsqrtR_LQR1VLabel_2  matlab.ui.control.Label
        RecalculateLQRButton_2          matlab.ui.control.Button
        RelativeStateWeightUITable_2    matlab.ui.control.Table
        UsePhiCheckBox_2                matlab.ui.control.CheckBox
        UseZaxisCheckBox_2              matlab.ui.control.CheckBox
        UseYaxisCheckBox_2              matlab.ui.control.CheckBox
        UseXaxisCheckBox_2              matlab.ui.control.CheckBox
        FeedbackgainTab_2               matlab.ui.container.Tab
        GridLayout12_2                  matlab.ui.container.GridLayout
        Estimatedoutputvoltagestandarddeviation3VLabel_2  matlab.ui.control.Label
        KLQRmatrixentriesLabel_2        matlab.ui.control.Label
        FbStdUITable_2                  matlab.ui.control.Table
        KLQRdispUITable_2               matlab.ui.control.Table
        IOcalibrationTab_2              matlab.ui.container.Tab
        GridLayout14_2                  matlab.ui.container.GridLayout
        InputADCcalibrationLabel_2      matlab.ui.control.Label
        OutputDACcalibrationLabel_2     matlab.ui.control.Label
        ADCCalibUITable_2               matlab.ui.control.Table
        DACCalibUITable_2               matlab.ui.control.Table
        AdvancedTab_2                   matlab.ui.container.Tab
        GridLayout16_2                  matlab.ui.container.GridLayout
        TargetstatestandarddeviationEditField_2  matlab.ui.control.NumericEditField
        TargetstatestandarddeviationEditField_2Label  matlab.ui.control.Label
        UndoTransformForPlotCheckBox_2  matlab.ui.control.CheckBox
        AdvancedTabLabel_2              matlab.ui.control.Label
        LimitTransformCheckBox_2        matlab.ui.control.CheckBox
        TransformStateSpaceCheckBox_2   matlab.ui.control.CheckBox
        ParticlemassEditField_2         matlab.ui.control.NumericEditField
        ParticlemassEditFieldLabel_2    matlab.ui.control.Label
        PhiprocessnoiseEditField_2      matlab.ui.control.NumericEditField
        PhiprocessnoiseEditFieldLabel_2  matlab.ui.control.Label
        ZprocessnoiseEditField_2        matlab.ui.control.NumericEditField
        ZprocessnoiseEditFieldLabel_2   matlab.ui.control.Label
        YprocessnoiseEditField_2        matlab.ui.control.NumericEditField
        YprocessnoiseEditFieldLabel_2   matlab.ui.control.Label
        XprocessnoiseEditField_2        matlab.ui.control.NumericEditField
        XprocessnoiseEditFieldLabel_2   matlab.ui.control.Label
        ZdampingEditField_2             matlab.ui.control.NumericEditField
        ZdampingEditFieldLabel_2        matlab.ui.control.Label
        YdampingEditField_2             matlab.ui.control.NumericEditField
        YdampingEditFieldLabel_2        matlab.ui.control.Label
        XdampingEditField_2             matlab.ui.control.NumericEditField
        XdampingEditFieldLabel_2        matlab.ui.control.Label
        PhiTimeConstEditField_2         matlab.ui.control.NumericEditField
        PhiTimeConstEditFieldLabel_2    matlab.ui.control.Label
        ZfrequencyEditField_2           matlab.ui.control.NumericEditField
        ZfrequencyEditFieldLabel_2      matlab.ui.control.Label
        YfrequencyEditField_2           matlab.ui.control.NumericEditField
        YfrequencyEditFieldLabel_2      matlab.ui.control.Label
        XfrequencyEditField_2           matlab.ui.control.NumericEditField
        XfrequencyEditFieldLabel_2      matlab.ui.control.Label
        UserGuideTab                    matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        DocsHTML                        matlab.ui.control.HTML
        LogTab                          matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        LogDisplay                      mlog.view.LogDisplay
        MatrixUITableContextMenu        matlab.ui.container.ContextMenu
        MultiplyTableByConstantMenu     matlab.ui.container.Menu
        DataPlotContextMenu             matlab.ui.container.ContextMenu
        FitbothaxestodataMenu           matlab.ui.container.Menu
        FitXaxistodataMenu              matlab.ui.container.Menu
        FitYaxistodataMenu              matlab.ui.container.Menu
        DirectoryContextMenu            matlab.ui.container.ContextMenu
        OpenfiledialogMenu              matlab.ui.container.Menu
        ImportParametersContextMenu     matlab.ui.container.ContextMenu
        GeneratetemplatejsonMenu        matlab.ui.container.Menu
    end

    
    properties (Access = private)
        % Fixed channel ordering used by selector, recorder, and plotting.
        DMAChannelNames = {'ADC1'; 'ADC2'; 'DAC1'; 'DAC2'; 'X'; 'dX'; 'Y'; 'dY'; 'Z'; 'dZ'; 'Phi'}; % Description
        XRelatedHandles1;
        YRelatedHandles1;
        ZRelatedHandles1;
        PhiRelatedHandles1;

        XRelatedHandles2;
        YRelatedHandles2;
        ZRelatedHandles2;
        PhiRelatedHandles2;

        parsTab1Handles;
        parsTab2Handles;

        NeedConnectionHandles;
        DemoMode = false;
        Connected = false;
        LoggerName = "LQG-FP7";
        docsDir;

        parameterSet1;
        parameterSet2;
        activeParStruct;

        NStates = 7;
        NADCs = 2;
        NDACs = 2;

        InputMatrixMaxLimit = 1;
        OutputMatrixMaxLimit = 2^5;
        ShiftBits = 5;

        TsIPCore = 64e-9;
        TsDMA
        TsBase = 8e-9;
        parameterPushOK = false;

        RPIPAddress = "0.0.0.0";
        BaseURL = "";
        % Reserved DMA memory budget in bytes; should match FPGA reservation.
        maxDMABytes = 1024*1024*256; 

        dataPlotStruct;
        DMAConfStruct;
        
        %dataBinPt = flip([16, 16, 16, 16, 8, 8, 8, 8, 8, 8, 15, 15, 15, 15]);
        % Per-channel plotting colors aligned with DMAChannelNames ordering.
        plotColors = { ...
            % ADCs
            [0.00 0.45 0.70];   % ADC0
            [0.00 0.75 0.75];   % ADC1
            % DACs
            [0.00 0.00 0.00];   % DAC0
            [0.95 0.80 0.10];   % DAC1
            % States
            [0.90 0.40 0.00];   % X1
            [1.00 0.70 0.20];   % X2
            [0.80 0.10 0.30];   % X3
            [0.95 0.45 0.80];   % X4
            [0.55 0.60 0.00];   % X5
            [0.65 0.85 0.30];   % X6
            [0.50 0.50 0.50];   % Phi    
        };

    end
    
    methods (Access = private)
        
        function finalValue = openMultiplyByConstDialog(app)
            % Prompt for a scalar multiplier used by matrix table context menu.
            % Returns NaN when canceled or closed.
            mainCoords = app.UIFigure.Position; %[left, bottom, width, height]
            dlgWidth = 250;
            dlgHeight = 150;
            dlgCoords = [mainCoords(1) + mainCoords(3)/2 - dlgWidth/2,...
                         mainCoords(2) + mainCoords(4)/2 - dlgHeight/2,...
                         dlgWidth, ...
                         dlgHeight];
            % Create the modal dialog
            d = uifigure('Name', 'Input Value', 'Position', dlgCoords, 'WindowStyle', 'modal');
        
            uilabel(d, 'Text', 'Enter Numeric Value:', 'Position', [20 100 150 22]);
            numField = uieditfield(d, 'numeric', 'Position', [20 70 210 25], 'Value', 0);
        
            % Accept Button: Resumes execution
            uibutton(d, 'Text', 'Accept', 'Position', [40 20 80 25], ...
                'ButtonPushedFcn', @(btn, event) uiresume(d));
        
            % Cancel Button: Also resumes, but we handle the NaN logic
            uibutton(d, 'Text', 'Cancel', 'Position', [130 20 80 25], ...
                'ButtonPushedFcn', @(btn, event) cancelCallback(d));
        
            % Execution pauses here until uiresume(d) or the figure is deleted
            uiwait(d);
        
            % After uiresume or closing the window:
            if isvalid(d)
                % If the dialog still exists, it means Accept or Cancel was pressed
                % Check if the cancel flag was set (handled by checking a custom property or state)
                if strcmp(d.UserData, 'Cancel')
                    finalValue = NaN;
                else
                    finalValue = numField.Value;
                end
                delete(d);
            else
                % If the user closed the window via the 'X' button
                finalValue = NaN;
            end
            
            % --- Local Helper for Cancel ---
            function cancelCallback(dialogFig)
                dialogFig.UserData = 'Cancel'; % Mark as cancel
                uiresume(dialogFig);
            end
        end
        
        function setPropertyForCells(app,cellarray,property,value)
            % Apply one property update across a collection of UI handles.
            for i = 1:numel(cellarray)
                cellarray{i}.(property) = value;
            end
        end

        function pStructOut = recalculateLQG(app, pStructIn)
            % Recompute LQR and Kalman objects from current model matrices.
            % Produces continuous/discrete gains and covariance estimates used
            % later for display and FPGA configuration packing.

            p = pStructIn;
            Rd_KF = p.P_V_mat/p.Ts;

            if p.use_phi && ~p.use_phi_for_LQR
                stateIdxsLQR = 1:(size(p.A_mat,1)-1);
            else
                stateIdxsLQR = 1:size(p.A_mat,1);
            end
            
            % Get the feedback gain
            p.K_LQR = lqrd(p.A_mat(stateIdxsLQR,stateIdxsLQR), p.B_mat(stateIdxsLQR,:), p.Q_LQR_mat(stateIdxsLQR,stateIdxsLQR), p.R_LQR_mat, p.Ts);
            p.K_LQR_c = lqr(p.A_mat(stateIdxsLQR,stateIdxsLQR), p.B_mat(stateIdxsLQR,:), p.Q_LQR_mat(stateIdxsLQR,stateIdxsLQR), p.R_LQR_mat, 0);
            
            if p.use_phi && ~p.use_phi_for_LQR
                p.K_LQR = padarray(p.K_LQR, [0, 1],0,'post'); 
                p.K_LQR_c = padarray(p.K_LQR_c, [0, 1],0,'post'); 
            end


            % Get the Kalman gain
            BG_mat = [p.B_mat, p.G_mat];
            p.knownIns = 1:size(p.B_mat,2);
            p.sensors = 1:size(p.C_mat,1);

            p.ssys_c = ss(p.A_mat, BG_mat, p.C_mat, 0);
            p.ssys_d = c2d(p.ssys_c,p.Ts);
            [p.kest_c, p.L_c_mat, P_err_c, ~, ~, ~] = kalman(p.ssys_c, p.P_W_mat, p.P_V_mat ,0,p.sensors,p.knownIns);
            [p.kest_d, p.L_d_mat, P_err_d, ~, ~, ~] = kalman(p.ssys_d, p.P_W_mat/p.Ts, p.P_V_mat/p.Ts ,0,p.sensors,p.knownIns,'delayed');

            % Get theoretical feedback signal standard deviation
            P_est = lyap(p.A_mat-p.B_mat*p.K_LQR_c, p.L_c_mat*p.P_V_mat*p.L_c_mat');
            P_est_nofb = lyap(p.A_mat, p.L_c_mat*p.P_V_mat*p.L_c_mat');
            P_est_nofb_d = dlyap(p.ssys_d.A, p.L_d_mat*(p.P_V_mat/p.Ts)*p.L_d_mat');
            p.P_cl = P_est + P_err_c;
            p.P_U = p.K_LQR_c*P_est*p.K_LQR_c';
            p.U_std = sign(p.P_U).*sqrt(abs(p.P_U));
            %disp(3*p.U_std);

            % Get the configuration matrices
            p.A_LC_d_mat = p.kest_d.A;
            p.B_d_mat = p.ssys_d.B(:,p.knownIns);
            
            pStructOut = p;
        end

        function pStructOut = parseParameterSet1(app)
            % Read parameter set 1 from UI and parse them into an internal
            % structure.
            p.Ts = app.TsIPCore;
            p.m = app.ParticlemassEditField.Value;
            if p.m == 0
                error('Mass of the particle must be greater than zero!')
            end
            p.f_x = app.XfrequencyEditField.Value;
            p.f_y = app.YfrequencyEditField.Value;
            p.f_z = app.ZfrequencyEditField.Value;
            p.T_phi = app.PhiTimeConstEditField.Value;
            p.Gamma_x = app.XdampingEditField.Value*2*pi;
            p.Gamma_y = app.YdampingEditField.Value*2*pi;
            p.Gamma_z = app.ZdampingEditField.Value*2*pi;
            p.P_W_x = app.XprocessnoiseEditField.Value;
            p.P_W_y = app.YprocessnoiseEditField.Value;
            p.P_W_z = app.ZprocessnoiseEditField.Value;
            p.P_W_phi = app.PhiprocessnoiseEditField.Value;
            p.C_mat_full = app.CVmUITable.Data;
            p.B_mat_full = app.CNVUITable.Data'./p.m;
            p.P_V_mat_full = app.MeasnoisematrixUITable.Data;
            p.R_LQR_full = app.FbGainUITable.Data.^2;
            p.Q_LQR_usr = app.RelativeStateWeightUITable.Data;
            p.use_x = app.UseXaxisCheckBox.Value;
            p.use_y = app.UseYaxisCheckBox.Value;
            p.use_z = app.UseZaxisCheckBox.Value;
            p.use_phi = app.UsePhiCheckBox.Value;

            p.w_x = 2*pi*p.f_x;
            p.w_y = 2*pi*p.f_y;
            p.w_z = 2*pi*p.f_z;
            
            modelFormat = str2num(app.InternalmodelformatDropDown.Value);

            p.closedLoopForm = modelFormat(1);
            p.transformedModel = modelFormat(2);

            p.use_ADC1 = any(p.C_mat_full(1,:));
            p.use_ADC2 = any(p.C_mat_full(2,:));
            p.use_DAC1 = any(p.B_mat_full(:,1));
            p.use_DAC2 = any(p.B_mat_full(:,2));

            p.use_phi_for_LQR = any(p.B_mat_full(end,:)) && p.use_phi;
            
            ADC_calib_data = app.ADCCalibUITable.Data;
            p.ADC1_factor = ADC_calib_data(1,1);
            p.ADC2_factor = ADC_calib_data(2,1);
            p.ADC1_offset = ADC_calib_data(1,2);
            p.ADC2_offset = ADC_calib_data(2,2);

            DAC_calib_data = app.DACCalibUITable.Data;
            p.DAC1_factor = DAC_calib_data(1,1);
            p.DAC2_factor = DAC_calib_data(2,1);
            p.DAC1_offset = DAC_calib_data(1,2);
            p.DAC2_offset = DAC_calib_data(2,2);

            p.numericalTransform = app.TransformStateSpaceCheckBox.Value;
            p.limitTransform = app.LimitTransformCheckBox.Value;
            p.undoTransform = app.UndoTransformForPlotCheckBox.Value;
            p.targetStateCov = app.TargetstatestandarddeviationEditField.Value^2;

            pStructOut = p;
        end
        
        function pStructOut = parseParameterSet2(app)
            % Read parameter set 2 from UI and parse them into an internal
            % structure.
            p.Ts = app.TsIPCore;
            p.m = app.ParticlemassEditField_2.Value;
            if p.m == 0
                error('Mass of the particle must be greater than zero!')
            end
            p.f_x = app.XfrequencyEditField_2.Value;
            p.f_y = app.YfrequencyEditField_2.Value;
            p.f_z = app.ZfrequencyEditField_2.Value;
            p.T_phi = app.PhiTimeConstEditField_2.Value;
            p.Gamma_x = app.XdampingEditField_2.Value*2*pi;
            p.Gamma_y = app.YdampingEditField_2.Value*2*pi;
            p.Gamma_z = app.ZdampingEditField_2.Value*2*pi;
            p.P_W_x = app.XprocessnoiseEditField_2.Value;
            p.P_W_y = app.YprocessnoiseEditField_2.Value;
            p.P_W_z = app.ZprocessnoiseEditField_2.Value;
            p.P_W_phi = app.PhiprocessnoiseEditField_2.Value;
            p.C_mat_full = app.CVmUITable_2.Data;
            p.B_mat_full = app.CNVUITable_2.Data'./p.m;
            p.P_V_mat_full = app.MeasnoisematrixUITable_2.Data;
            p.R_LQR_full = app.FbGainUITable_2.Data.^2;
            p.Q_LQR_usr = app.RelativeStateWeightUITable_2.Data;
            p.use_x = app.UseXaxisCheckBox_2.Value;
            p.use_y = app.UseYaxisCheckBox_2.Value;
            p.use_z = app.UseZaxisCheckBox_2.Value;
            p.use_phi = app.UsePhiCheckBox_2.Value;

            p.w_x = 2*pi*p.f_x;
            p.w_y = 2*pi*p.f_y;
            p.w_z = 2*pi*p.f_z;
            
            modelFormat = str2num(app.InternalmodelformatDropDown_2.Value);

            p.closedLoopForm = modelFormat(1);
            p.transformedModel = modelFormat(2);

            p.use_ADC1 = any(p.C_mat_full(1,:));
            p.use_ADC2 = any(p.C_mat_full(2,:));
            p.use_DAC1 = any(p.B_mat_full(:,1));
            p.use_DAC2 = any(p.B_mat_full(:,2));

            p.use_phi_for_LQR = any(p.B_mat_full(end,:)) && p.use_phi;
            
            ADC_calib_data = app.ADCCalibUITable_2.Data;
            p.ADC1_factor = ADC_calib_data(1,1);
            p.ADC2_factor = ADC_calib_data(2,1);
            p.ADC1_offset = ADC_calib_data(1,2);
            p.ADC2_offset = ADC_calib_data(2,2);

            DAC_calib_data = app.DACCalibUITable_2.Data;
            p.DAC1_factor = DAC_calib_data(1,1);
            p.DAC2_factor = DAC_calib_data(2,1);
            p.DAC1_offset = DAC_calib_data(1,2);
            p.DAC2_offset = DAC_calib_data(2,2);

            p.numericalTransform = app.TransformStateSpaceCheckBox_2.Value;
            p.limitTransform = app.LimitTransformCheckBox_2.Value;
            p.undoTransform = app.UndoTransformForPlotCheckBox_2.Value;
            p.targetStateCov = app.TargetstatestandarddeviationEditField_2.Value^2;

            pStructOut = p;
        end
        
        function distributeParametersTab1(app, p)
            % Update frontend of parameter set 1 with values from a
            % parameter structure.

            % --- Edit Fields ---
            app.ParticlemassEditField.Value      = p.m;
            app.XfrequencyEditField.Value        = p.f_x;
            app.YfrequencyEditField.Value        = p.f_y;
            app.ZfrequencyEditField.Value        = p.f_z;
            app.PhiTimeConstEditField.Value      = p.T_phi;
            
            % Reverse the 2*pi scaling
            app.XdampingEditField.Value          = p.Gamma_x / (2*pi);
            app.YdampingEditField.Value          = p.Gamma_y / (2*pi);
            app.ZdampingEditField.Value          = p.Gamma_z / (2*pi);
            
            app.XprocessnoiseEditField.Value     = p.P_W_x;
            app.YprocessnoiseEditField.Value     = p.P_W_y;
            app.ZprocessnoiseEditField.Value     = p.P_W_z;
            app.PhiprocessnoiseEditField.Value   = p.P_W_phi;
        
            % --- Tables ---
            app.CVmUITable.Data                  = p.C_mat_full;
            
            % Reverse the transpose and division by mass
            app.CNVUITable.Data                  = (p.B_mat_full * p.m)';
            
            app.MeasnoisematrixUITable.Data      = p.P_V_mat_full;
            
            % Reverse the square (ensure data is non-negative before sqrt if necessary)
            app.FbGainUITable.Data               = sqrt(p.R_LQR_full);
            
            app.RelativeStateWeightUITable.Data  = p.Q_LQR_usr;
        
            % --- Checkboxes ---
            app.UseXaxisCheckBox.Value           = p.use_x;
            app.UseYaxisCheckBox.Value           = p.use_y;
            app.UseZaxisCheckBox.Value           = p.use_z;
            app.UsePhiCheckBox.Value             = p.use_phi;
        
            % --- Calibration Tables ---
            % Reconstruct the 2x2 matrices for ADC and DAC
            app.ADCCalibUITable.Data = [p.ADC1_factor, p.ADC1_offset; ...
                                        p.ADC2_factor, p.ADC2_offset];
                                    
            app.DACCalibUITable.Data = [p.DAC1_factor, p.DAC1_offset; ...
                                        p.DAC2_factor, p.DAC2_offset];
        end

        function distributeParametersTab2(app, p)
            % Update frontend of parameter set 2 with values from a
            % parameter structure.

            % --- Edit Fields ---
            app.ParticlemassEditField_2.Value      = p.m;
            app.XfrequencyEditField_2.Value        = p.f_x;
            app.YfrequencyEditField_2.Value        = p.f_y;
            app.ZfrequencyEditField_2.Value        = p.f_z;
            app.PhiTimeConstEditField_2.Value      = p.T_phi;
            
            % Reverse the 2*pi scaling
            app.XdampingEditField_2.Value          = p.Gamma_x / (2*pi);
            app.YdampingEditField_2.Value          = p.Gamma_y / (2*pi);
            app.ZdampingEditField_2.Value          = p.Gamma_z / (2*pi);
            
            app.XprocessnoiseEditField_2.Value     = p.P_W_x;
            app.YprocessnoiseEditField_2.Value     = p.P_W_y;
            app.ZprocessnoiseEditField_2.Value     = p.P_W_z;
            app.PhiprocessnoiseEditField_2.Value   = p.P_W_phi;
        
            % --- Tables ---
            app.CVmUITable_2.Data                  = p.C_mat_full;
            
            % Reverse the transpose and division by mass
            app.CNVUITable_2.Data                  = (p.B_mat_full * p.m)';
            
            app.MeasnoisematrixUITable_2.Data      = p.P_V_mat_full;
            
            % Reverse the square (ensure data is non-negative before sqrt if necessary)
            app.FbGainUITable_2.Data               = sqrt(p.R_LQR_full);
            
            app.RelativeStateWeightUITable_2.Data  = p.Q_LQR_usr;
        
            % --- Checkboxes ---
            app.UseXaxisCheckBox_2.Value           = p.use_x;
            app.UseYaxisCheckBox_2.Value           = p.use_y;
            app.UseZaxisCheckBox_2.Value           = p.use_z;
            app.UsePhiCheckBox_2.Value             = p.use_phi;
       
        
            % --- Calibration Tables ---
            % Reconstruct the 2x2 matrices for ADC and DAC
            app.ADCCalibUITable_2.Data = [p.ADC1_factor, p.ADC1_offset; ...
                                        p.ADC2_factor, p.ADC2_offset];
                                    
            app.DACCalibUITable_2.Data = [p.DAC1_factor, p.DAC1_offset; ...
                                        p.DAC2_factor, p.DAC2_offset];
        end
        
        function pStructOut = assembleStateSpace(app, pStructIn)
            % Assemble reduced state-space model based on enabled axes/IO.
            % Extracts active A,B,C,Q,R,Pv,Pw submatrices.
            p = pStructIn;
            idxsX = 1:2;
            idxsY = 3:4;
            idxsZ = 5:6;
            idxsPhi = 7;
            
            idxsDACs = [p.use_DAC1*1, p.use_DAC2*2];
            idxsDACs(idxsDACs == 0) = [];
            p.idxsDACs = idxsDACs;
            p.NDACs = numel(idxsDACs);
            if isempty(idxsDACs)
                warning("The system doesn't have any actuation! (B matrix is empty)")
            end

            idxsADCs = [p.use_ADC1*1, p.use_ADC2*2];
            idxsADCs(idxsADCs == 0) = [];
            p.idxsADCs = idxsADCs;
            p.NADCs = numel(idxsADCs);
            if isempty(idxsADCs)
                error("The system is not measurable! (C matrix is empty)")
            end
            idxsStates = [p.use_x*idxsX, p.use_y*idxsY, p.use_z*idxsZ, p.use_phi*idxsPhi];
            idxsStates(idxsStates == 0) = [];
            p.idxsStates = idxsStates;
            p.NStates = numel(idxsStates);
            if isempty(idxsStates) %~any([p.use_x, p.use_y, p.use_z, p.use_phi])
                error("No dynamics enabled!")
            end

            A_x = [0, 1; -sign(p.w_x)*abs(p.w_x)^2, -p.Gamma_x];
            A_y = [0, 1; -sign(p.w_y)*abs(p.w_y)^2, -p.Gamma_y];
            A_z = [0, 1; -sign(p.w_z)*abs(p.w_z)^2, -p.Gamma_z];
            A_phi = -1/p.T_phi;
            B_x = p.B_mat_full(idxsX, idxsDACs);
            B_y = p.B_mat_full(idxsY, idxsDACs);
            B_z = p.B_mat_full(idxsZ, idxsDACs);
            B_phi = p.B_mat_full(idxsPhi, idxsDACs);
            C_x = p.C_mat_full(idxsADCs, idxsX);
            C_y = p.C_mat_full(idxsADCs, idxsY);
            C_z = p.C_mat_full(idxsADCs, idxsZ);
            C_phi = p.C_mat_full(idxsADCs, idxsPhi);
            
            % Scale user Q weights with energy-like normalization terms.
            sqrtQ_vec = sqrt(0.5*p.m*[abs(p.w_x)^2, 1, abs(p.w_y)^2, 1, abs(p.w_z)^2, 1, 1]); 
            Q_LQR_full = p.Q_LQR_usr.*(sqrtQ_vec'*sqrtQ_vec);
            
            G_xyz = [0; 1/p.m];

            A_mat = [];
            B_mat = [];
            G_mat = [];
            C_mat = [];
            %Q_LQR_mat = Q_LQR_full; %TODO
            P_W_mat = [];
            %P_V_mat = [];
            %R_LQR_mat = [];
            Q_idcs = [];
            targetCov = [];

            if p.use_x
                A_mat = A_x;
                B_mat = B_x;
                C_mat = C_x;
                G_mat = G_xyz;
                P_W_mat = p.P_W_x;
                Q_idcs = idxsX;
            end
            if p.use_y
                A_mat = blkdiag(A_mat,A_y);
                B_mat = [B_mat;B_y];
                C_mat = [C_mat,C_y];
                %Q_LQR_mat = blkdiag(Q_LQR_mat, Q_LQR_full(idxsY,idxsY));
                G_mat = blkdiag(G_mat,G_xyz);
                P_W_mat = blkdiag(P_W_mat, p.P_W_y);
                Q_idcs = [Q_idcs, idxsY];
            end
            if p.use_z
                A_mat = blkdiag(A_mat,A_z);
                B_mat = [B_mat;B_z];
                C_mat = [C_mat,C_z];
                %Q_LQR_mat = blkdiag(Q_LQR_mat, Q_LQR_full(idxsY,idxsY));
                G_mat = blkdiag(G_mat,G_xyz);
                P_W_mat = blkdiag(P_W_mat, p.P_W_z);
                Q_idcs = [Q_idcs, idxsZ];
            end
            if p.use_phi
                A_mat = blkdiag(A_mat,A_phi);
                B_mat = [B_mat;B_phi];
                C_mat = [C_mat,C_phi];
                %Q_LQR_mat = blkdiag(Q_LQR_mat, Q_LQR_full(idxsY,idxsY));
                G_mat = blkdiag(G_mat,1);
                P_W_mat = blkdiag(P_W_mat, p.P_W_phi);
                Q_idcs = [Q_idcs, idxsPhi];
            end
            
            Q_LQR_mat = Q_LQR_full(Q_idcs,Q_idcs);
            P_V_mat = p.P_V_mat_full(idxsADCs,idxsADCs);
            R_LQR_mat = p.R_LQR_full(idxsDACs,idxsDACs);

            p.A_mat = A_mat;
            p.B_mat = B_mat;
            p.G_mat = G_mat;
            p.C_mat = C_mat;
            p.Q_LQR_mat = Q_LQR_mat;
            p.R_LQR_mat = R_LQR_mat;
            p.P_V_mat = P_V_mat;
            p.P_W_mat = P_W_mat;

            if p.transformedModel
                p = app.transformStateSpace(p);
            end

            pStructOut = p;

        end

        function pStructOut = transformStateSpace(app, pStructIn)
            % Apply deterministic similarity transform for state normalization.
            p = pStructIn;
            p.C_ref = max(abs(p.C_mat_full),[],"all");

            T_mat = [];
            if p.use_x
                T_x = diag([1/p.C_ref, abs(p.w_x)/p.C_ref]);
                T_mat = T_x;
            end
            if p.use_y
                T_y = diag([1/p.C_ref, abs(p.w_y)/p.C_ref]);
                T_mat = blkdiag(T_mat,T_y);
            end
            if p.use_z
                T_z = diag([1/p.C_ref, abs(p.w_z)/p.C_ref]);
                T_mat = blkdiag(T_mat,T_z);
            end
            if p.use_phi
                T_phi = diag([1/p.C_ref]);
                T_mat = blkdiag(T_mat,T_phi);
            end

            p.T_mat = T_mat;
            
            p.A_mat_nom = p.A_mat;
            p.B_mat_nom = p.B_mat;
            p.G_mat_nom = p.G_mat;
            p.C_mat_nom = p.C_mat;
            p.Q_LQR_mat_nom = p.Q_LQR_mat;
            p.R_LQR_mat_nom = p.R_LQR_mat;
            p.P_V_mat_nom = p.P_V_mat;
            p.P_W_mat_nom = p.P_W_mat;

            p.A_mat = T_mat\p.A_mat_nom*T_mat;
            p.B_mat = T_mat\p.B_mat_nom;
            p.G_mat = T_mat\p.G_mat_nom;
            p.C_mat = p.C_mat_nom*T_mat;
            p.Q_LQR_mat = T_mat'*p.Q_LQR_mat*T_mat;

         
            pStructOut = p;
        end

        function pStructOut = optimizedTransformStateSpace(app, pStructIn)
            % Compute additional diagonal scaling to improve numerical ranges
            % and help satisfy fixed-point limits before FPGA export.

            p = pStructIn;
            nStates = size(p.A_mat,1);

            targetCovMat = eye(nStates)*p.targetStateCov;

            P_est_fun = @(T) dlyap(T\(p.ssys_d.A-p.B_d_mat*p.K_LQR_c)*T, (T\p.L_d_mat)*(p.P_V_mat/p.Ts)*(T\p.L_d_mat)'); 
            P_est_0 = P_est_fun(diag(ones(1,nStates)));

            scaleMethod = "cholesky";

            if scaleMethod == "cholesky"
                T_chol = chol(P_est_0)*sqrt(inv(targetCovMat));
                T_bal = diag(diag(T_chol));
            elseif scaleMethod == "optimization"
                %Err_0 = targetCovDiag - reshape(diag(P_est_0),1,[]);
                objFun = @(diagT) sum((diag(targetCovMat) - reshape(diag(P_est_fun(diag(diagT))),1,[])).^2);
                objFun0 = objFun(ones(nStates,1));
                opts = optimoptions('fmincon','OptimalityTolerance',1e-14);
                T_diagElmnts = fmincon(@(T) objFun(T)/objFun0,ones(nStates,1),[],[],[],[],eps*ones(nStates,1),[], [],opts);
                T_bal = diag(T_diagElmnts);
            end

            maxKVal = max(abs(p.K_LQR_c*T_bal),[],"all");
            maxInputVal = max(abs(T_bal\[p.L_d_mat, p.B_d_mat]),[],'all');
            if (maxKVal*maxInputVal > 1)
                %warning("System cannot be accurately represented in the fixed point representation.")
            end
            
            % Clamp numerical range to fit into the HW datatypes
            if p.limitTransform && (maxKVal > app.OutputMatrixMaxLimit) && (maxInputVal <= app.InputMatrixMaxLimit)
                T_inout = app.OutputMatrixMaxLimit*eye(nStates)/maxKVal;
            elseif p.limitTransform && (maxKVal <=app.OutputMatrixMaxLimit) && (maxInputVal > app.InputMatrixMaxLimit)
                T_inout = app.InputMatrixMaxLimit*eye(nStates)*maxInputVal;
            else
                T_inout = eye(nStates);
            end
            
            T_opt = T_bal*T_inout;
            p.T_opt = T_opt;
            p.T_mat_opt = T_opt;
            p.A_LC_d_nom = p.A_LC_d_mat;
            p.A_LC_d_mat = T_opt\p.A_LC_d_mat*T_opt;

            p.B_d_nom = p.B_d_mat;
            p.B_d_mat = T_opt\p.B_d_mat;

            p.L_d_nom = p.L_d_mat;
            p.L_d_mat = T_opt\p.L_d_mat;

            p.K_LQR_c_nom = p.K_LQR_c;
            p.K_LQR_c = p.K_LQR_c*T_opt;
         
            pStructOut = p;
        end

        function pStructOut = padStateSpace(app, pStructIn)
            % Pad reduced matrices back to fixed 7-state / 2-input layout
            % expected by downstream fixed-size FPGA configuration.
            p = pStructIn;
            
            % Define state indices for each axis
            idxsX = 1:2;
            idxsY = 3:4;
            idxsZ = 5:6;
            idxsPhi = 7;

            idxsDACs = [p.use_DAC1, p.use_DAC2];
            idxsADCs = [p.use_ADC1, p.use_ADC2];
            
            % Collect active indices based on which axes are used
            idxsStates = [];
            if p.use_x
                idxsStates = [idxsStates, idxsX];
            end
            if p.use_y
                idxsStates = [idxsStates, idxsY];
            end
            if p.use_z
                idxsStates = [idxsStates, idxsZ];
            end
            if p.use_phi
                idxsStates = [idxsStates, idxsPhi];
            end

            % Pad A_LC_d_mat (7x7)
            p.A_LC_d_padded = zeros(7, 7);
            p.A_LC_d_padded(idxsStates, idxsStates) = p.A_LC_d_mat;
            
            % Pad B_d_mat (7x2)
            p.B_d_padded = zeros(7, 2);
            p.B_d_padded(idxsStates, idxsDACs) = p.B_d_mat;
            
            % Pad K_LQR_mat (2x7)
            p.K_LQR_c_padded = zeros(2, 7);
            p.K_LQR_c_padded(idxsDACs, idxsStates) = p.K_LQR_c;
            
            % Pad L_d_mat (7x2) - measurement feedback gains
            p.L_d_padded = zeros(7, 2);
            p.L_d_padded(idxsStates, idxsADCs) = p.L_d_mat;
            
            % Pad U_std for plotting
            p.U_std_padded = zeros(2,2);
            p.U_std_padded(idxsDACs, idxsDACs) = p.U_std;
            
            %Pad transformation matrix
            p.T_mat_padded = eye(7);
            p.T_mat_padded(idxsStates,idxsStates) = p.T_mat_opt;
            
            
            pStructOut = p;
        end

        
        function [recordIdxs, recordFlags, recordNames, plotIdxs, plotFlags, plotNames] = getChannelSelection(app)
            % Read record/plot masks from selector table.
            % Assumes channel order is [ADCs; DACs; States].
            tableContent = app.SignalSelectorUITable.Data;
            
            recordFlags = vertcat(tableContent{:,2});
            recordIdxs = find(recordFlags);
            recordNames = {tableContent{recordFlags,1}};

            plotFlags = vertcat(tableContent{:,3});
            plotIdxs = find(plotFlags);
            plotNames = {tableContent{plotFlags,1}};
        end
        
        function updateDataPlot(app)
            % Apply calibrations/inverse transform and render either time data
            % or PSD view depending on plot style selection.

            dPS = app.dataPlotStruct;
            plotStyle = app.PlotStyleSwitch.Value;
            
            idxADC1 = find(convertCharsToStrings(dPS.Labels)=="ADC1");
            if ~isempty(idxADC1) && isfield(app.activeParStruct,'ADC1_factor') && isfield(app.activeParStruct,'ADC1_offset') && ~app.DemoMode
                dPS.Data(idxADC1,:) = app.activeParStruct.ADC1_factor*(dPS.Data(idxADC1,:) + app.activeParStruct.ADC1_offset);
            end
            idxADC2 = find(convertCharsToStrings(dPS.Labels)=="ADC2");
            if ~isempty(idxADC2) && isfield(app.activeParStruct,'ADC2_factor') && isfield(app.activeParStruct,'ADC2_offset') && ~app.DemoMode
                dPS.Data(idxADC2,:) = app.activeParStruct.ADC2_factor*(dPS.Data(idxADC2,:) + app.activeParStruct.ADC2_offset);
            end
            idxDAC1 = find(convertCharsToStrings(dPS.Labels)=="DAC1");
            if ~isempty(idxDAC1) && isfield(app.activeParStruct,'DAC1_factor') && isfield(app.activeParStruct,'DAC1_offset') && ~app.DemoMode
                dPS.Data(idxDAC1,:) = ((dPS.Data(idxDAC1,:) - app.activeParStruct.DAC1_offset))/app.activeParStruct.DAC1_factor;
            end
            idxDAC2 = find(convertCharsToStrings(dPS.Labels)=="DAC2");
            if ~isempty(idxDAC2) && isfield(app.activeParStruct,'DAC2_factor') && isfield(app.activeParStruct,'DAC2_offset') && ~app.DemoMode
                dPS.Data(idxDAC2,:) = (dPS.Data(idxDAC2,:) - app.activeParStruct.DAC2_offset)/app.activeParStruct.DAC2_factor;
            end
            if isfield(app.activeParStruct,'undoTransform') && isfield(app.activeParStruct,'T_mat_padded') && app.activeParStruct.undoTransform
                T_diag = diag(app.activeParStruct.T_mat_padded);
                idxsT = 1:7;
                stateLabels = app.DMAChannelNames(5:end);
                for i = 1:numel(idxsT)
                    idx = find(convertCharsToStrings(dPS.Labels)==stateLabels{i});
                    if ~isempty(idx)
                        dPS.Data(idx,:) = T_diag(idxsT(i))\dPS.Data(idx,:);
                    end
                end
            end
            
            % 1. Calculate Sampling Frequency from time vector
            % $F_s = 1 / \Delta t$
            TsData = dPS.t_vec(2)-dPS.t_vec(1);
            FsData = 1 / TsData;
            
            % 2. Prepare Data based on Style
            if strcmp(plotStyle, 'Time')
                plotX = dPS.t_vec;
                plotY = dPS.Data;
                xLab  = "Time (s)";
                yLab  = "Amplitude";
                app.dataPlotStruct.LastPlotType = "Time";
            else
                % Calculate PSD for each channel
                % Using periodogram for simplicity, or pwelch if averaging is required
                [pxx, f] = pwelch(dPS.Data', [], [],[], FsData);
                plotX = f;
                plotY = pxx'; % Transpose to match dPS.Data orientation (Channels x Samples)
                xLab  = "Frequency (Hz)";
                yLab  = "Power/Frequency (dB/Hz)";
                app.dataPlotStruct.LastPlotType = "PSD";
            end
            
            % 3. Determine if we can "Update" or must "Redraw"
            % We force a redraw if the PlotType changed (Time vs PSD) 
            % because the X-axis length and units will be different.
            typeChanged = ~isfield(dPS, 'LastPlotType') || ~strcmp(dPS.LastPlotType, plotStyle);
            TsChanged = dPS.NewTs ~= dPS.LastTs;
            if ~typeChanged && (dPS.LastDataFrames == dPS.NewDataFrames) && ...
                    (dPS.LastDataChannels == dPS.NewDataChannels)
                
                % Only update the lines (Efficient)
                for i = 1:size(plotY, 1)
                    % Note: Children are stored in reverse order of plotting
                    childIdx = dPS.NewDataChannels - i + 1;
                    app.DataAxes.Children(childIdx).XData = plotX;
                    app.DataAxes.Children(childIdx).YData = plotY(i, :);
                    app.DataAxes.Children(childIdx).Color = dPS.LineColors{i};
                end
                drawnow limitrate;
            else
                % Redraw everything
                hold(app.DataAxes, "off");
                for i = 1:size(plotY, 1)
                    p = plot(app.DataAxes, plotX, plotY(i, :), ...
                        "Color", dPS.LineColors{i}, ...
                        "DisplayName", dPS.Labels{i});
                    hold(app.DataAxes, "on");
                    
                    % If PSD, typically use a log scale for the Y-axis
                    if strcmp(plotStyle, 'PSD')
                        set(app.DataAxes, 'YScale', 'log');
                        set(app.DataAxes, 'XScale', 'log');
                    else
                        set(app.DataAxes, 'YScale', 'linear');
                        set(app.DataAxes, 'XScale', 'linear');
                    end
                end
                grid(app.DataAxes, "on");
                xlabel(app.DataAxes, xLab);
                ylabel(app.DataAxes, yLab);
            end

            legend(app.DataAxes, 'Visible', 'on');
            app.dataPlotStruct.LastDataFrames = dPS.NewDataFrames;
            app.dataPlotStruct.LastDataChannels = dPS.NewDataChannels;
            app.dataPlotStruct.YLims = [min(plotY,[],"all"), max(plotY,[],"all")];
            app.dataPlotStruct.XLims = [min(plotX,[],"all"), max(plotX,[],"all")];
            app.dataPlotStruct.LastTs = dPS.NewTs;
            if typeChanged || TsChanged
                app.FitbothaxestodataMenuSelected;
            end
        end
        
        function lqg = assembleLQGStruct(app, parsStruct)
            % Convert active parameter struct into FPGA payload:
            % shift/residue decomposition of F, Gamma, L, and K matrices.

            DEBUG = false; % Set to true to use a model of seven 1st order lowpasses
            p = parsStruct;
            shift_multiple = 1;

            if p.closedLoopForm
                p.A_LC_d_padded = p.A_LC_d_padded-p.B_d_padded*p.K_LQR_c_padded;
                p.B_d_padded = 0*p.B_d_padded;
            end

            if strcmp(app.EnablefeedbackSwitch.Value, "Off")
                p.K_LQR_c_padded = 0*p.K_LQR_c_padded;
            end

            % Calculate shift decompositions of LQG matrices.
            shift_min = 0;
            shift_max = (2^app.ShiftBits)-1;
            if ~DEBUG
                [lqg.F_ip_shift, lqg.F_ip_residue] = shift_decompose(p.A_LC_d_padded, shift_multiple, shift_min, shift_max);
                [lqg.Gamma_ip_shift, lqg.Gamma_ip_residue] = shift_decompose(p.B_d_padded, shift_multiple, shift_min, shift_max);
                [lqg.L_ip_shift, lqg.L_ip_residue] = shift_decompose(p.L_d_padded, shift_multiple, shift_min, shift_max);
                % FLIP SIGN FOR THE FEEDBACK GAIN!
                [lqg.K_ip_shift, lqg.K_ip_residue] = shift_decompose(-p.K_LQR_c_padded, shift_multiple, [], shift_max);
            else
                beta = exp(-1e5*p.Ts);
                F_ip = zeros(7);
                %F_ip(3,1) = beta;
                %F_ip(4,1) = beta/2;
                F_ip = beta*eye(7);
                %F_ip(1:2,:) = flip(F_ip(1:2,:));
                %F_ip(1,1) = 0;
                Gamma = zeros(7,2);
                L_ip = zeros(7,2);
                L_ip(:,1) = 1-beta;
                %L_ip(1,1) = 0;
                K_ip = zeros(2,7);
                K_ip(1,1) = 1;
                K_ip(2,6) = 1;

                [lqg.F_ip_shift, lqg.F_ip_residue] = shift_decompose(F_ip, shift_multiple, shift_min, shift_max);
                [lqg.Gamma_ip_shift, lqg.Gamma_ip_residue] = shift_decompose(Gamma, shift_multiple, shift_min, shift_max);
                [lqg.L_ip_shift, lqg.L_ip_residue] = shift_decompose(L_ip, shift_multiple, shift_min, shift_max);
                % FLIP SIGN FOR THE FEEDBACK GAIN!
                [lqg.K_ip_shift, lqg.K_ip_residue] = shift_decompose(K_ip, shift_multiple, [], shift_max);
            end

            lqg.yk_offset = 2*[p.ADC1_offset, p.ADC2_offset];
            lqg.yk_factor = 0.5*[p.ADC1_factor, p.ADC2_factor];
            lqg.uk_offset = 2*[p.DAC1_offset, p.DAC2_offset];
            lqg.uk_factor = 2*[p.DAC1_factor, p.DAC2_factor];
            lqg.led = 42;

            % display(lqg.K_ip_residue .* (2 .^ -lqg.K_ip_shift));
            % display(clqr.Kc_ip);
        end

        function loadParameters(app, path, setN)
            load(path, 'parsStruct')
            fields = {parsStruct(:).name};
            for i = 1:length(fields)
                if setN > 1
                    name = fields{i}+"_"+int2str(setN);
                else
                    name = fields{i};
                end
                if isprop(app, name)
                    comp = app.(name);
                    c = string(class(comp)); 
                    if c == "matlab.ui.control.Table"
                        comp.Data = parsStruct(i).value;
                    else
                        comp.Value = parsStruct(i).value;
                    end
                else
                    disp(['Did not use saved field', name])
                end
            end
        end

        function handleListOut = getInteractiveElementsFromParent(app, parent)
            c = class(parent);
            if isprop(parent,"Children") && (c ~= "matlab.ui.control.Table")
                % Traverse the children tree recursively
                handleList = struct([]);
                childrenArray = parent.Children;
                for i = 1:numel(childrenArray)
                    handleListNew = getInteractiveElementsFromParent(app,childrenArray(i));
                    if isfield(handleListNew,'handle')
                        handleList = [handleList, handleListNew];
                    end
                end
                handleListOut = handleList;
            else
                c = class(parent);
                if c == "matlab.ui.control.NumericEditField" ...
                        || c == "matlab.ui.control.EditField" ...
                        || c == "matlab.ui.control.CheckBox" ...
                        || c == "matlab.ui.control.DropDown" ...
                        || c == "matlab.ui.control.Table"
                    handleListOut = struct();
                    handleListOut.handle = parent; %Return handle if the element is interactive
                else
                    handleListOut = []; %Return empty if not
                end
            end
        
        end

        function structOut = findNamesForHandles(app, handleStruct)
            props = properties(app);
            for i = 1:length(props)
                name = props{i};
                comp = app.(name);
                idx = find(vertcat(handleStruct(:).handle)==comp);
                if ~isempty(idx)
                    nameStruct(idx).name = erase(name,'_2');
                    if class(comp) == "matlab.ui.control.Table"
                        nameStruct(idx).value = comp.Data;
                    else
                        nameStruct(idx).value = comp.Value;
                    end
                end
            end
            structOut = nameStruct;
        end
        
        function [data, t_vec] = retrieveAndDecodeData(app, NFrames)
            % RETRIEVEANDDECODEDATA Retrieves a specific number of frames from DMA memory
            % without reconfiguring or triggering a new recording.
            import matlab.net.http.*;
            logObj = mlog.Logger(app.LoggerName);
            
            if ~isfield(app.DMAConfStruct,'frames')
                msgObj = logObj.error("No data recorded yet, unable to retrieve any.");
                msgObj.toDialog(app.UIFigure, "Optional Dialog Title");
                data = [];
                t_vec = [];
                return;
            end

            % 1. Extract necessary constants/settings from the app
            baseURL = app.BaseURL;
            maxFrames = app.DMAConfStruct.frames;
            decimationFactor = app.DMAConfStruct.decimation; %multiples of the base frequency
            channelIdxs = app.DMAConfStruct.channels; %Recorded channels in memory; +1 to get one-indexing
            numChannels = numel(channelIdxs);

            if NFrames > maxFrames
                msgObj = logObj.error("Request for retrieving "+int2str(NFrames)+" frames from memory, while only "+int2str(maxFrames)+" are available");
                msgObj.toDialog(app.UIFigure, "Optional Dialog Title");
                data = [];
                t_vec = [];
                return;
            end

            % 2. Calculate data requirements
            % Each sample is 4 bytes (standard for this DMA implementation)
            numberOfBytes = NFrames * numChannels * 4; %number of frames*channels*4 bytes per channels
            f_samp = 1/app.TsBase / decimationFactor; %Base frequency/decimation (64ns alignment included in decimationFactor)
            
            try
                % 3. Retrieve raw data from memory starting at offset 0
                [raw_data, resp_status] = dma_get_data_blockwise(baseURL, 0, numberOfBytes);
                
                if resp_status ~= StatusCode.OK
                    msgObj = logObj.error("Data retrieval failed. Status code: " + resp_status);
                    msgObj.toDialog(app.UIFigure, "Optional Dialog Title");
                    data = [];
                    t_vec = [];
                    return;
                end
                
                logObj.info("Successfully retrieved " + int2str(numberOfBytes) + " bytes from existing memory.");
        
                % 4. Decode the raw stream into channel-based matrices
                decodedData = decode_dma_stream(raw_data, channelIdxs);
                
                % 5. Generate time vector
                t_vec = (1:1:NFrames) / f_samp;
                
                % 6. Construct the output structure (matching app.dataPlotStruct)
                data = decodedData(:, :);
            catch err
                msgObj = logObj.error("An error occurred during data retrieval.");
                logObj.write(err);
                msgObj.toDialog(app.UIFigure, "Optional Dialog Title");
                data = [];
                t_vec = [];
            end
        end

        
        function state_reset_ok = resetInternalStates(app)
            import matlab.net.http.*;
            logObj = mlog.Logger(app.LoggerName);
            state_reset_ok = false;
            resp_status = write_axi(app.BaseURL, "axi_sys_s_axi", "axi_state_reset", 1);
            if resp_status == StatusCode.OK
                resp_status = write_axi(app.BaseURL, "axi_sys_s_axi", "axi_state_reset", 0);
                if resp_status == StatusCode.OK
                    state_reset_ok = true;
                end
            end

            if ~state_reset_ok
                msgObj = logObj.error("State reset after disabling feedback failed with status code"+resp_status+".");
                msgObj.toDialog(app.UIFigure, "Error");
            else
                logObj.info("Reset LQG states after disabling feedback.");
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    		% Initialize paths, default matrices, docs view, logger, and UI state.
            % credits to https://www.mathworks.com/matlabcentral/answers/1741325-how-can-i-get-the-full-path-of-my-current-mlapp-file
            if isdeployed 
			    % User is running an executable in standalone mode. 
			    [~ , result] = system('set PATH');
			    executableFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
		    else
			    % User is running an m-file from the MATLAB integrated development environment (regular MATLAB).
                tmp_path = mfilename('fullpath');
                [executableFolder , ~ , ~] = fileparts(tmp_path);
                addpath(fullfile(executableFolder, "build"));
                addpath(fullfile(executableFolder, "srcs"));
                app.docsDir = fullfile(executableFolder, "docs");
                addpath(app.docsDir);
                %addpath(fullfile(app.docsDir,"pages"));
                addpath(fullfile(executableFolder, "srcs","API"));
                addpath(fullfile(executableFolder, "srcs","DMA"));
		    end 
            
            defaultDataPath = fullfile(executableFolder,"data","fpgaData.mat");
            app.OutputfilepathEditField.Value = defaultDataPath;

            NStates = app.NStates;
            
            app.docsDir = fullfile(executableFolder, "docs");
            app.DocsHTML.HTMLSource = fullfile(app.docsDir,"index.html");

            % Fill signal selector table
            app.SignalSelectorUITable.ColumnFormat = {'char','logical','logical'};
            NRows = numel(app.DMAChannelNames);
            logicPart = cell(NRows, 2);
            logicPart(:) = {true};
            tableData = horzcat(app.DMAChannelNames, logicPart);
            app.SignalSelectorUITable.Data = tableData;
            
            NADCs = app.NADCs;
            NDACs = app.NDACs;
            % Prefill C_Vm table(s)
            app.CVmUITable.ColumnFormat = repmat({'numeric'},1,NStates);
            app.CVmUITable.ColumnEditable = true(1,NStates);
            app.CVmUITable_2.ColumnFormat = repmat({'numeric'},1,NStates);
            app.CVmUITable_2.ColumnEditable = true(1,NStates);
            CVmData = zeros(NADCs,NStates);
            CVmData = [2e5, 0,  5e4, 0, 2e4, 0, 2e5;...
                       0,   0, -3e4, 0, 2e4, 0, 0];
            app.CVmUITable.Data = CVmData;
            app.CVmUITable_2.Data = CVmData;
            
            %Prefill C_NV table(s)
            app.CNVUITable.ColumnFormat = repmat({'numeric'},1,NStates);
            app.CNVUITable.ColumnEditable = true(1,NStates);
            app.CNVUITable_2.ColumnFormat = repmat({'numeric'},1,NStates);
            app.CNVUITable_2.ColumnEditable = true(1,NStates);
            CNVData = zeros(NDACs,NStates);
            CNVData = [0, 5e-13, 0,  1e-13, 0, 5e-13, 0;...
                       0, 1e-13, 0,  5e-13, 0, 1e-13, 0];
            app.CNVUITable.Data = CNVData;
            app.CNVUITable_2.Data = CNVData;

            %Prefill P_V tables
            app.MeasnoisematrixUITable.ColumnFormat = repmat({'numeric'},1,NADCs);
            app.MeasnoisematrixUITable.ColumnEditable = true(1,NADCs);
            app.MeasnoisematrixUITable_2.ColumnFormat = repmat({'numeric'},1,NADCs);
            app.MeasnoisematrixUITable_2.ColumnEditable = true(1,NADCs);
            PVData = eye(NADCs);
            PVData = diag([4e-12, 4e-12]);
            app.MeasnoisematrixUITable.Data = PVData;
            app.MeasnoisematrixUITable_2.Data = PVData;

            %Prefill R_lqr tables
            app.FbGainUITable.ColumnFormat = repmat({'numeric'},1,NDACs);
            app.FbGainUITable.ColumnEditable = true(1,NDACs);
            app.FbGainUITable_2.ColumnFormat = repmat({'numeric'},1,NDACs);
            app.FbGainUITable_2.ColumnEditable = true(1,NDACs);
            RLQRData = eye(NDACs);
            RLQRData = diag([1e-10, 1e-10]);
            app.FbGainUITable.Data = RLQRData;
            app.FbGainUITable_2.Data = RLQRData;

            %Prefill Q_usr table
            app.RelativeStateWeightUITable.ColumnFormat = repmat({'numeric'},1,NStates);
            app.RelativeStateWeightUITable.ColumnEditable = true(1,NStates);
            app.RelativeStateWeightUITable_2.ColumnFormat = repmat({'numeric'},1,NStates);
            app.RelativeStateWeightUITable_2.ColumnEditable = true(1,NStates);
            QUSRData = eye(NStates);
            app.RelativeStateWeightUITable.Data = QUSRData;
            app.RelativeStateWeightUITable_2.Data = QUSRData;

            %Prefill ADC tables
            app.ADCCalibUITable.ColumnFormat = repmat({'numeric'},1,NADCs);
            app.ADCCalibUITable.ColumnEditable = true(1,NADCs);
            app.ADCCalibUITable_2.ColumnFormat = repmat({'numeric'},1,NADCs);
            app.ADCCalibUITable_2.ColumnEditable = true(1,NADCs);
            ADCCalibData = [1, 0; 1, 0];
            app.ADCCalibUITable.Data = ADCCalibData;
            app.ADCCalibUITable_2.Data = ADCCalibData;

            %Prefill DAC tables
            app.DACCalibUITable.ColumnFormat = repmat({'numeric'},1,NDACs);
            app.DACCalibUITable.ColumnEditable = true(1,NDACs);
            app.DACCalibUITable_2.ColumnFormat = repmat({'numeric'},1,NDACs);
            app.DACCalibUITable_2.ColumnEditable = true(1,NDACs);
            DACCalibData = [1, 0; 1, 0];
            app.DACCalibUITable.Data = DACCalibData;
            app.DACCalibUITable_2.Data = DACCalibData;

            app.XRelatedHandles1 = {app.XfrequencyEditField, app.XfrequencyEditFieldLabel,...
                                    app.XdampingEditField, app.XdampingEditFieldLabel,...
                                    app.XprocessnoiseEditField, app.XprocessnoiseEditFieldLabel};
            app.YRelatedHandles1 = {app.YfrequencyEditField, app.YfrequencyEditFieldLabel,...
                                    app.YdampingEditField, app.YdampingEditFieldLabel, ...
                                    app.YprocessnoiseEditField, app.YprocessnoiseEditFieldLabel};
            app.ZRelatedHandles1 = {app.ZfrequencyEditField, app.ZfrequencyEditFieldLabel,...
                                    app.ZdampingEditField, app.ZdampingEditFieldLabel,...
                                    app.ZprocessnoiseEditField, app.ZprocessnoiseEditFieldLabel};
            app.PhiRelatedHandles1 = {app.PhiTimeConstEditField, app.PhiTimeConstEditFieldLabel, ...
                                      app.PhiprocessnoiseEditField, app.PhiprocessnoiseEditFieldLabel};

            app.XRelatedHandles2 = {app.XfrequencyEditField_2, app.XfrequencyEditFieldLabel_2,...
                                    app.XdampingEditField_2, app.XdampingEditFieldLabel_2,...
                                    app.XprocessnoiseEditField_2, app.XprocessnoiseEditFieldLabel_2};
            app.YRelatedHandles2 = {app.YfrequencyEditField_2, app.YfrequencyEditFieldLabel_2,...
                                    app.YdampingEditField_2, app.YdampingEditFieldLabel_2, ...
                                    app.YprocessnoiseEditField_2, app.YprocessnoiseEditFieldLabel_2};
            app.ZRelatedHandles2 = {app.ZfrequencyEditField_2, app.ZfrequencyEditFieldLabel_2,...
                                    app.ZdampingEditField_2, app.ZdampingEditFieldLabel_2,...
                                    app.ZprocessnoiseEditField_2, app.ZprocessnoiseEditFieldLabel_2};
            app.PhiRelatedHandles2 = {app.PhiTimeConstEditField_2, app.PhiTimeConstEditFieldLabel_2, ...
                                      app.PhiprocessnoiseEditField_2, app.PhiprocessnoiseEditFieldLabel_2};
            
            app.NeedConnectionHandles = {app.RecorddataButton, ...
                                         app.EnablefeedbackSwitch, app.EnablefeedbackLabel,...
                                         app.ParametersetselectSwitch, app.ParametersetselectSwitchLabel,...
                                         app.PushparametersButton, app.ResetinternalstatesButton};

            app.dataPlotStruct.Data = [];
            app.dataPlotStruct.LastDataFrames = 0;
            app.dataPlotStruct.LastDataChannels = 0;
            app.dataPlotStruct.NewDataFrames = 0;
            app.dataPlotStruct.NewDataChannels = 0;
            app.dataPlotStruct.Labels = 0;
            app.dataPlotStruct.LineColors = {};
            app.dataPlotStruct.ModePSD = false;
            app.dataPlotStruct.YLims = [-1 1];
            app.dataPlotStruct.XLims = [0 1];
            app.dataPlotStruct.LastTs = 1;
            app.dataPlotStruct.NewTs = 1;
            
            % Initialize parameter stucts
            %app.RecalculateLQRButtonPushed([]);

            % Logger test
            logObj = mlog.Logger(app.LoggerName);
            %logObj.clearLog;
            logObj.FileThreshold = "MESSAGE";
            %logObj.LogFolder = ".\logs";
            logObj.LogFolder = fullfile(executableFolder, "logs");
            logObj.RotationPeriod = "day"; 
            msgObj = logObj.message("================== Session started ==================");
            
            app.parsTab1Handles = getInteractiveElementsFromParent(app, app.Parameterset1Tab);
            app.parsTab2Handles = getInteractiveElementsFromParent(app, app.Parameterset2Tab);


        end

        % Cell edit callback: SignalSelectorUITable
        function SignalSelectorUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2) == 3 && newData == true
                app.SignalSelectorUITable.Data{indices(1),2} = true;
            elseif indices(2) == 2 && newData == false
                app.SignalSelectorUITable.Data{indices(1),3} = false;
            end
        end

        % Menu selected function: MultiplyTableByConstantMenu
        function MultiplyTableByConstantMenuSelected(app, event)
            % Get the Table that owns this Context Menu
            targetTable = event.ContextObject;
            
            finalValue = openMultiplyByConstDialog(app);
            if ~isnan(finalValue)
                dataOld = targetTable.Data;
                targetTable.Data = finalValue.*dataOld;
            end
            
        end

        % Value changed function: UseXaxisCheckBox, UseXaxisCheckBox_2
        function UseXaxisCheckBoxValueChanged(app, event)
            tabHandle = event.Source.Parent.Parent.Parent.Parent.Parent;
            switch tabHandle.Tag
                case 'ParametersTab1'
                    switchHandles = app.XRelatedHandles1;
                    value = app.UseXaxisCheckBox.Value;
                case 'ParametersTab2'
                    switchHandles = app.XRelatedHandles2;
                    value = app.UseXaxisCheckBox_2.Value;
            end
            
            if value
                app.setPropertyForCells(switchHandles,'Enable',true);
            else
                app.setPropertyForCells(switchHandles,'Enable',false);
            end
        end

        % Value changed function: UseYaxisCheckBox, UseYaxisCheckBox_2
        function UseYaxisCheckBoxValueChanged(app, event)
            tabHandle = event.Source.Parent.Parent.Parent.Parent.Parent;
            switch tabHandle.Tag
                case 'ParametersTab1'
                    switchHandles = app.YRelatedHandles1;
                    value = app.UseYaxisCheckBox.Value;
                case 'ParametersTab2'
                    switchHandles = app.YRelatedHandles2;
                    value = app.UseYaxisCheckBox_2.Value;
            end
            
            if value
                app.setPropertyForCells(switchHandles,'Enable',true);
            else
                app.setPropertyForCells(switchHandles,'Enable',false);
            end
        end

        % Value changed function: UseZaxisCheckBox, UseZaxisCheckBox_2
        function UseZaxisCheckBoxValueChanged(app, event)
            tabHandle = event.Source.Parent.Parent.Parent.Parent.Parent;
            switch tabHandle.Tag
                case 'ParametersTab1'
                    switchHandles = app.ZRelatedHandles1;
                    value = app.UseZaxisCheckBox.Value;
                case 'ParametersTab2'
                    switchHandles = app.ZRelatedHandles2;
                    value = app.UseZaxisCheckBox_2.Value;
            end
            
            if value
                app.setPropertyForCells(switchHandles,'Enable',true);
            else
                app.setPropertyForCells(switchHandles,'Enable',false);
            end
        end

        % Value changed function: UsePhiCheckBox, UsePhiCheckBox_2
        function UsePhiCheckBoxValueChanged(app, event)
            tabHandle = event.Source.Parent.Parent.Parent.Parent.Parent;
            switch tabHandle.Tag
                case 'ParametersTab1'
                    switchHandles = app.PhiRelatedHandles1;
                    value = app.UsePhiCheckBox.Value;
                case 'ParametersTab2'
                    switchHandles = app.PhiRelatedHandles2;
                    value = app.UsePhiCheckBox_2.Value;
            end
            
            if value
                app.setPropertyForCells(switchHandles,'Enable',true);
            else
                app.setPropertyForCells(switchHandles,'Enable',false);
            end
        end

        % Button pushed function: RecalculateLQRButton, 
        % ...and 1 other component
        function RecalculateLQRButtonPushed(app, event)
            tabHandle = event.Source.Parent.Parent.Parent.Parent.Parent;
            switch tabHandle.Tag
                case 'ParametersTab1'
                    set = 1;
                    parSet = app.parseParameterSet1;
                case 'ParametersTab2'
                    set = 2;
                    parSet = app.parseParameterSet2;
            end

            parSet = app.assembleStateSpace(parSet);
            parSet = app.recalculateLQG(parSet);
            if parSet.numericalTransform
                parSet = app.optimizedTransformStateSpace(parSet);
            else
                parSet.T_mat_opt = eye(parSet.NStates);
            end
            parSet = app.padStateSpace(parSet);
            
            if parSet.undoTransform
                invT = inv(parSet.T_mat_padded);
            else
                invT = eye(7);
            end

            switch set
                case 1
                    app.KLQRdispUITable.Data = parSet.K_LQR_c_padded*invT;
                    app.FbStdUITable.Data = parSet.U_std_padded;
                    app.parameterSet1 = parSet;
                case 2
                    app.KLQRdispUITable_2.Data = parSet.K_LQR_c_padded*invT;
                    app.FbStdUITable_2.Data = parSet.U_std_padded;
                    app.parameterSet2 = parSet;
            end
            
            
        end

        % Value changed function: DemoModeSwitch
        function DemoModeSwitchValueChanged(app, event)
            logObj = mlog.Logger(app.LoggerName);
            value = app.DemoModeSwitch.Value;
            if strcmp(value,"On")
                if app.Connected
                    fig = app.UIFigure;
                    msg = "The app seems to be connected to hardware, do you want to enable the demo mode?";
                    title = "Confirm the demo mode";
                    selection = uiconfirm(fig,msg,title, ...
                        "Options",["Enable demo mode","Cancel"], ...
                        "DefaultOption",1,"CancelOption",2);
                    if strcmp(selection,"Enable demo mode")
                        app.DemoMode = true;
                    else
                        app.DemoModeSwitch.Value = "Off";
                    end
                else
                    app.DemoMode = true;
                end
            else
                app.DemoMode = false;
            end


            if app.DemoMode
                app.ConnectStateButton.Enable = false;
                app.setPropertyForCells(app.NeedConnectionHandles,'Enable',true);
                logObj.message("Demo mode enabled");
            else
                app.ConnectStateButton.Enable = true;
                if app.Connected == false
                    app.setPropertyForCells(app.NeedConnectionHandles,'Enable',false);
                end
                logObj.message("Demo mode disabled");
            end
        end

        % Value changed function: ConnectStateButton
        function ConnectStateButtonValueChanged(app, event)
            value = app.ConnectStateButton.Value;
            import matlab.net.http.*;

            logObj = mlog.Logger(app.LoggerName);

            if value == true
                % Fill in the actual connection logic
                app.RPIPAddress = app.RedPitayaIPAddressEditField.Value;
                app.BaseURL = "http://"+app.RPIPAddress+":5000/api";

                try
                    resp_status = write_axi(app.BaseURL, "axi_sys_s_axi", "axi_led_mask", 0xff);
                    
                    if resp_status ~= StatusCode.OK
                        msgObj = logObj.error("Connection failed with status code "+resp_status);
                        msgObj.toDialog(app.UIFigure, "Optional Dialog Title");
                        connectionSuccessful = false;
                    else
                        connectionSuccessful = true;
                    end

                catch err
                    logObj.write(err);
                    connectionSuccessful = false;
                end

                if connectionSuccessful
                    logObj.info("Successfully connected to the Red Pitaya at "+app.RPIPAddress)
                    app.Connected = true;
                    app.setPropertyForCells(app.NeedConnectionHandles,'Enable',true);
                    app.ConnectStateButton.BackgroundColor = [0.72,1,0.72];
                    app.ConnectStateButton.Text = "Click to disconnect";
                else
                    app.Connected = false;
                    app.ConnectStateButton.Value = false;
                end

            else
                
                try
                    resp_status = write_axi(app.BaseURL, "axi_sys_s_axi", "axi_led_mask", 0x00);
                    
                    if resp_status ~= StatusCode.OK
                        msgObj = logObj.error("Disconnection failed with status code "+resp_status);
                        msgObj.toDialog(app.UIFigure, "Optional Dialog Title");
                        disconnectionSuccessful = false;
                    else
                        disconnectionSuccessful = true;
                    end

                catch err
                    logObj.write(err);
                    disconnectionSuccessful = false;
                end

                if disconnectionSuccessful
                    logObj.info("Successfully disconnected from the Red Pitaya at "+app.RPIPAddress)
                    app.Connected = false;
                    app.setPropertyForCells(app.NeedConnectionHandles,'Enable',false);
                    app.ConnectStateButton.BackgroundColor = [1.00,0.5,0.5];
                    app.ConnectStateButton.Text = "Click to connect";
                else
                    app.Connected = true;
                    app.ConnectStateButton.Value = true;
                end
            end
        end

        % Button pushed function: RecorddataButton
        function RecorddataButtonPushed(app, event)
            import matlab.net.http.*;
            logObj = mlog.Logger(app.LoggerName);

            progDlg = uiprogressdlg(app.UIFigure,'Title','Recording data',...
                'Indeterminate','on');
            drawnow
            

            NrecordFrames = app.NumberofframestorecordEditField.Value;
            NplotFrames = app.NumberofframestoplotEditField.Value;
            app.TsDMA = app.RecordingDecimationEditField.Value*app.TsIPCore*2;

            [recordIdxs, recordFlags, recordNames, plotIdxs, plotFlags, plotNames] = app.getChannelSelection;

            plotFromRecordedFlags = plotFlags(recordFlags);

            if app.DemoMode
                % Work with simulated system
                if strcmp(app.EnablefeedbackSwitch.Value,'On')
                    FbEnable = true;
                else
                    FbEnable = false;
                end
                
                % if strcmp(app.ParametersetselectSwitch.Value, "Set 1")
                %     parStruct = app.parameterSet1;
                % else
                %     parStruct = app.parameterSet2;
                % end
                if isempty(app.activeParStruct)
                    app.PushparametersButtonPushed;
                end

                parStruct = app.activeParStruct;

                Xest_0 = zeros(app.NStates,1);
                Xtrue_0 = zeros(app.NStates,1);

                %Reverse transform of the feedback gain
                if parStruct.numericalTransform && isfield(parStruct,'T_mat_opt')
                    K_LQR = parStruct.K_LQR_c/parStruct.T_mat_opt;
                    L_d_mat = parStruct.T_mat_opt*parStruct.L_d_mat;
                else
                    K_LQR = parStruct.K_LQR_c;
                    L_d_mat = parStruct.L_d_mat;
                end

                [ADC_data, DAC_data, Xest_data, t_vec] = runSimulation_mex(...
                    parStruct.idxsDACs, parStruct.idxsADCs, parStruct.idxsStates, ...
                    parStruct.P_W_mat, parStruct.P_V_mat, parStruct.B_mat, ...
                    parStruct.ssys_d.A, parStruct.ssys_d.B, parStruct.ssys_d.C, ...
                    parStruct.kest_d.A, parStruct.kest_d.B, ...
                    L_d_mat, K_LQR, ...
                    NplotFrames, app.TsDMA, app.TsIPCore, Xest_0, Xtrue_0, FbEnable);
                %t_vec = 0:app.TsDMA:(app.TsDMA*(NplotFrames-1));

                fullIdxsADCs = 1:2;
                fullIdxsDACs = 3:4;
                fullIdxsStates = 5:11;

                plotData = [ADC_data(plotFlags(fullIdxsADCs),:); DAC_data(plotFlags(fullIdxsDACs),:); Xest_data(plotFlags(fullIdxsStates),:)]; 
                logObj.info("New simulated data acquired");
                
                decimation_factor = app.RecordingDecimationEditField.Value;
                conf = struct();
                conf.frames = NrecordFrames;
                conf.decimation = 8 * decimation_factor;
                conf.channels = get_dma_channel_idxs(recordFlags)-1; %-1 to get zero-indexing
                conf.trigger_source = "software";

            elseif ~app.DemoMode && app.Connected 
                % Connected to hardware

                decimation_factor = app.RecordingDecimationEditField.Value;
                f_samp = 125e6 / 8 / decimation_factor; % Base clock/IP core clock alignment alignment / decimation
                
                conf = struct();
                conf.frames = NrecordFrames;
                conf.decimation = 8 * decimation_factor;
                conf.channels = get_dma_channel_idxs(recordFlags)-1; %-1 to get zero-indexing
                conf.trigger_source = "software";
                
                numOfBytes = conf.frames*numel(conf.channels)*4;
                app.maxDMABytes = 1024*1024*app.ReservedRAMEditField.Value;
                maxFrames = floor(app.maxDMABytes/4/numel(conf.channels));
                if numOfBytes > app.maxDMABytes
                    selection = uiconfirm(app.UIFigure, ...
                    "The requested number of "+int2str(conf.frames)+" frames exceeds the memory capacity of "+...
                    int2str(maxFrames)+" frames with "+int2str(numel(conf.channels))+" channels. "+...
                    "Would you like to cap the number of frames, or cancel the recording?", ... 
                    "Too many frames requested", ...                % Title
                    "Options", ["Cap the frames", "Cancel"], ...    % Button labels
                    "DefaultOption", "Cap the frames");             % Default focus

                    % Map the selection to setN
                    if selection == "Cap the frames"
                        conf.frames = maxFrames;
                        NrecordFrames = maxFrames;
                        app.NumberofframestorecordEditField.Value = maxFrames;
                        if app.NumberofframestoplotEditField.Value > maxFrames
                            app.NumberofframestoplotEditField.Value = maxFrames;
                            NplotFrames = maxFrames;
                        end
                    else
                        return;
                        close(progDlg);
                    end
                end

                try
                    resp_status = dma_zero_mem(app.BaseURL);
                    if resp_status ~= StatusCode.OK
                        msgObj = logObj.warning("Data memory erasing failed with status code "+resp_status);
                        msgObj.toDialog(app.UIFigure, "Optional Dialog Title");
                    else
                        logObj.info("Data memory erased successfully");
                    end
                catch err
                    msgObj = logObj.warning("Data memory erasing failed");
                    msgObj.toDialog(app.UIFigure, "Optional Dialog Title");
                    logObj.write(err);
                end

                
                try
                    resp_status = dma_config(app.BaseURL, conf);
                    if resp_status ~= StatusCode.OK
                        msgObj = logObj.error("DMA configuration failed with status code "+resp_status);
                        msgObj.toDialog(app.UIFigure, "Optional Dialog Title");
                        close(progDlg);
                        return
                    else
                        logObj.info("DMA configured successfully");
                    end
                catch err
                    msgObj = logObj.error("DMA configuration failed");
                    logObj.write(err);
                    msgObj.toDialog(app.UIFigure, "Optional Dialog Title");
                    close(progDlg);
                    return
                end
                
                try
                    resp_status = dma_wait(app.BaseURL, 10.0);
                    if resp_status ~= StatusCode.OK
                        msgObj = logObj.error("Problem occured waiting for the DMA. Status code "+resp_status);
                        msgObj.toDialog(app.UIFigure, "Optional Dialog Title");
                        close(progDlg);
                        return
                    end
                catch err
                    msgObj = logObj.error("Problem occured waiting for the DMA.");
                    msgObj.toDialog(app.UIFigure, "Optional Dialog Title");
                    logObj.write(err);
                    close(progDlg);
                    return
                end

                app.DMAConfStruct = conf; %Save info what data are recorded in the memory
                [plotData, ~] = retrieveAndDecodeData(app, NplotFrames);
                if isempty(plotData)
                    close(progDlg);
                    return;
                end

                t_vec = (1:1:NplotFrames) / f_samp;

                plotFromRecordedFlags = plotFlags;
            end

            app.DMAConfStruct = conf;
            app.DMAConfStruct.recordedDataChannels = recordFlags;
            app.DMAConfStruct.recordedDataIdxs = recordIdxs;
            app.DMAConfStruct.recordedDataLabels = recordNames;

            app.dataPlotStruct.Data = plotData(plotFromRecordedFlags,1:NplotFrames);
            app.dataPlotStruct.t_vec = t_vec;
            app.dataPlotStruct.NewDataFrames = NplotFrames;
            app.dataPlotStruct.NewDataChannels = sum(plotFromRecordedFlags);
            app.dataPlotStruct.Labels = plotNames;
            app.dataPlotStruct.LineColors = app.plotColors(plotIdxs,1);
            app.dataPlotStruct.ModePSD = false;
            app.dataPlotStruct.NewTs = t_vec(2)-t_vec(1);
            
            app.updateDataPlot;
            close(progDlg);

        end

        % Menu selected function: FitbothaxestodataMenu
        function FitbothaxestodataMenuSelected(app, event)
            app.FitXaxistodataMenuSelected;
            app.FitYaxistodataMenuSelected;
            
        end

        % Menu selected function: FitXaxistodataMenu
        function FitXaxistodataMenuSelected(app, event)
            xlim(app.DataAxes,"auto")
            xlim(app.DataAxes,"tight")
            app.DataAxes.XLimMode = "auto";
            zoom(app.DataAxes,"reset")
        end

        % Menu selected function: FitYaxistodataMenu
        function FitYaxistodataMenuSelected(app, event)
            ylim(app.DataAxes,"auto")
            ylim(app.DataAxes,"tight")
            app.DataAxes.YLimMode = "auto";
            zoom(app.DataAxes,"reset")
        end

        % Value changed function: PlotStyleSwitch
        function PlotStyleSwitchValueChanged(app, event)
            value = app.PlotStyleSwitch.Value;
            app.updateDataPlot;
        end

        % Button pushed function: PushparametersButton
        function PushparametersButtonPushed(app, event)
            import matlab.net.http.*;
            setSwitchValue = app.ParametersetselectSwitch.Value;
            if app.Connected && ~app.DemoMode
                logObj = mlog.Logger(app.LoggerName);
                
                if strcmp(setSwitchValue, "Set 1")
                    event_.Source = app.RecalculateLQRButton;
                    app.RecalculateLQRButtonPushed(event_)
                    app.activeParStruct = app.parameterSet1;
                else
                    event_.Source = app.RecalculateLQRButton_2;
                    app.RecalculateLQRButtonPushed(event_)
                    app.activeParStruct = app.parameterSet2;
                end
                lqg = app.assembleLQGStruct(app.activeParStruct);
                app.parameterPushOK = false;
                try
                    progDlg = uiprogressdlg(app.UIFigure,'Title','Pushing parameters',...
                        'Indeterminate','on');
                    drawnow
                    [resp_data, resp_status] = update_config(app.BaseURL, lqg);
                    if resp_status ~= StatusCode.OK
                        msgObj = logObj.error("LQG configuration failed with status code "+resp_status+ ": "+resp_data.error);
                        msgObj.toDialog(app.UIFigure, "Optional Dialog Title");
                    else
                        logObj.info("LQG configured with parameter "+lower(setSwitchValue))
                    end
                    app.parameterPushOK = true;
                catch err
                    msgObj = logObj.error("Problem occured updating the LQG configuration:");
                    logObj.write(err);
                    msgObj.toDialog(app.UIFigure, "Optional Dialog Title");
                    close(progDlg);
                    app.parameterPushOK = false;
                end
                
                close(progDlg)
            else
                app.parameterPushOK = true;
                if strcmp(setSwitchValue, "Set 1")
                    event_.Source = app.RecalculateLQRButton;
                    app.RecalculateLQRButtonPushed(event_)
                    app.activeParStruct = app.parameterSet1;
                else
                    event_.Source = app.RecalculateLQRButton_2;
                    app.RecalculateLQRButtonPushed(event_)
                    app.activeParStruct = app.parameterSet2;
                end
            end
        end

        % Value changed function: EnablefeedbackSwitch
        function EnablefeedbackSwitchValueChanged(app, event)
            %Modify to change only the K_LQR matrix
            value = app.EnablefeedbackSwitch.Value;
            
            app.PushparametersButtonPushed(event);
            logObj = mlog.Logger(app.LoggerName);
            if app.parameterPushOK
                if strcmp(value, "Off")
                    logObj.info("Feedback output disabled.")
                else
                    logObj.info("Feedback output enabled.")
                end
            else
                if strcmp(value, "Off")
                    app.EnablefeedbackSwitch.Value = "On";
                else
                    app.EnablefeedbackSwitch.Value = "Off";
                end
            end
        end

        % Value changed function: ParametersetselectSwitch
        function ParametersetselectSwitchValueChanged(app, event)
            app.PushparametersButtonPushed(event);
            if app.parameterPushOK == false
                if strcmp(value, "Set 1")
                    app.ParametersetselectSwitch.Value = "Set 2";
                else
                    app.ParametersetselectSwitch.Value = "Set 1";
                end
            end
        end

        % Button pushed function: SaveparametersButton
        function SaveparametersButtonPushed(app, event)
            
            [file,loc] = uiputfile('*.mat','Select parameter file name','parameters.mat');
            figure(app.UIFigure)
            if file == 0
                % User clicked on cancel
                return
            else
                %filepath = app.OutputfilepathEditField.Value;
                filepath = fullfile(loc,file);
                pathParts = split(filepath, filesep);
                filename = erase(pathParts{end},'.mat');
    
                currentTimeStr = string(datetime('now','Format','yyyy-MM-dd-hh-mm'));
                pathSet1 = fullfile(pathParts{1:end-1},filename+"_"+currentTimeStr+"_set1.mat");
                pathSet2 = fullfile(pathParts{1:end-1},filename+"_"+currentTimeStr+"_set2.mat");

                
                parsStruct = findNamesForHandles(app, app.parsTab1Handles);
                save(pathSet1,'parsStruct');
                parsStruct = findNamesForHandles(app, app.parsTab2Handles);
                save(pathSet2,'parsStruct');
            end
        end

        % Button pushed function: LoadparametersButton
        function LoadparametersButtonPushed(app, event)
            % Add logic to decide into which 
            [file,loc] = uigetfile('*.mat');
            figure(app.UIFigure)
            if file == 0
                % User clicked on cancel
                return
            else
                selection = uiconfirm(app.UIFigure, ...
                    "Into which tab would you like to push the parameters?", ... % Message
                    "Select Parameter Set", ...                        % Title
                    "Options", ["Set 1", "Set 2"], ...                 % Button labels
                    "DefaultOption", "Set 1");                         % Default focus

                % Map the selection to setN
                if selection == "Set 1"
                    setN = 1;
                else
                    setN = 2;
                end
                app.loadParameters(string(fullfile(loc,file)),setN);      
                
                if setN == 1
                    app.UseXaxisCheckBoxValueChanged(struct('Source',app.UseXaxisCheckBox));
                    app.UseYaxisCheckBoxValueChanged(struct('Source',app.UseYaxisCheckBox));
                    app.UseZaxisCheckBoxValueChanged(struct('Source',app.UseZaxisCheckBox));
                    app.UsePhiCheckBoxValueChanged(struct('Source',app.UsePhiCheckBox));
                else
                    app.UseXaxisCheckBoxValueChanged(struct('Source',app.UseXaxisCheckBox_2));
                    app.UseYaxisCheckBoxValueChanged(struct('Source',app.UseYaxisCheckBox_2));
                    app.UseZaxisCheckBoxValueChanged(struct('Source',app.UseZaxisCheckBox_2));
                    app.UsePhiCheckBoxValueChanged(struct('Source',app.UsePhiCheckBox_2));
                end
            end
        end

        % Button pushed function: SavematfileButton
        function SavematfileButtonPushed(app, event)
            filepath = app.OutputfilepathEditField.Value;
            pathParts = split(filepath, filesep);
            logObj = mlog.Logger(app.LoggerName);

            % 1. Extract the folder path and check/create it
            folderPath = fullfile(pathParts{1:end-1});
            if ~isempty(folderPath) && ~exist(folderPath, 'dir')
                mkdir(folderPath);
            end

            filename = erase(pathParts{end},'.mat');
            currentTimeStr = string(datetime('now','Format','yyyy-MM-dd-hh-mm-ss'));
            path = fullfile(pathParts{1:end-1},filename+currentTimeStr+".mat");
            
            %app.SavematfileButton.Enable = false;
            progDlg = uiprogressdlg(app.UIFigure,'Title','Fetching and exporting data',...
                'Indeterminate','on');
            drawnow
            %Save data to matlab file
            if app.Connected && ~app.DemoMode
                if ~isfield(app.DMAConfStruct,'frames')
                    msgObj = logObj.error("No data recorded yet, unable to retrieve any.");
                    msgObj.toDialog(app.UIFigure, "Optional Dialog Title");
                    %app.SavematfileButton.Enable = true;
                    close(progDlg)
                    return;
                end
                [recData, t_vec] = retrieveAndDecodeData(app, app.DMAConfStruct.frames);
                if isempty(recData)
                    %app.SavematfileButton.Enable = true;
                    close(progDlg)
                    return;
                end
                recLabels = app.DMAConfStruct.recordedDataLabels;
                idxADC1 = find(convertCharsToStrings(recLabels)=="ADC1");
                if ~isempty(idxADC1) && isfield(app.activeParStruct,'ADC1_factor') && isfield(app.activeParStruct,'ADC1_offset')
                    recData(idxADC1,:) = app.activeParStruct.ADC1_factor*(recData(idxADC1,:) + app.activeParStruct.ADC1_offset);
                end
                idxADC2 = find(convertCharsToStrings(recLabels)=="ADC2");
                if ~isempty(idxADC2) && isfield(app.activeParStruct,'ADC2_factor') && isfield(app.activeParStruct,'ADC2_offset')
                    recData(idxADC2,:) = app.activeParStruct.ADC2_factor*(recData(idxADC2,:) + app.activeParStruct.ADC2_offset);
                end
                if isfield(app.activeParStruct,'undoTransform') && isfield(app.activeParStruct,'T_mat_padded') && app.activeParStruct.undoTransform
                    T_diag = diag(app.activeParStruct.T_mat_padded);
                    idxsT = 1:7;
                    stateLabels = app.DMAChannelNames(5:end);
                    for i = 1:numel(idxsT)
                        idx = find(convertCharsToStrings(recLabels)==stateLabels{i});
                        if ~isempty(idx)
                            recData(idx,:) = T_diag(idxsT(i))\recData(idx,:);
                        end
                    end
                end

            else
                recData = app.dataPlotStruct.Data;
                t_vec = app.dataPlotStruct.t_vec;
            end

            dataStruct.Data = recData(app.DMAConfStruct.recordedDataIdxs,:);
            dataStruct.t_vec = t_vec;
            dataStruct.Labels = app.DMAConfStruct.recordedDataLabels;
            dataStruct.pars = app.activeParStruct;
            
            
            try
                save(path,"dataStruct");
            catch err
                %Add handling
                msgObj = logObj.error("Error saving .mat file");
                logObj.write(err);
                %app.SavematfileButton.Enable = true;
                msgObj.toDialog(app.UIFigure, "Optional Dialog Title");
                close(progDlg)
            end
            msgObj = logObj.info("Data exported successfully");
            msgObj.toDialog(app.UIFigure, "Optional Dialog Title");

            %app.SavematfileButton.Enable = true;
            close(progDlg)
        end

        % Context menu opening function: DirectoryContextMenu
        function DirectoryContextMenuOpening(app, event)
            [file,dir] = uigetfile('.mat','Select directory for measurements','fpgaData.mat');
            figure(app.UIFigure)
            if dir == 0
                % User clicked on cancel
                return
            else
                app.OutputfilepathEditField.Value = fullfile(dir,file);
            end
        end

        % Button pushed function: ImportparametersButton
        function ImportparametersButtonPushed(app, event)
            missingColor = [1 0.8 0.8];   % Light red
            normalColor  = [1 1 1];       % White

            %%Get the file
            [filename, dir] = uigetfile('.json','Select .json file with parameters');
            
            if filename ~= 0 %Valid file selected
                filepath = fullfile(dir, filename);
                jsonLines = fileread(filepath);
                parStruct = jsondecode(jsonLines);

                answer = questdlg("Which parameter set do you want to update?", ...
	                "Parameter set choice", ...
	                'Set 1','Set 2','Set 1');
                % Handle response
                switch answer
                    case 'Set 1'
                        distributeParametersTab1(app, parStruct);
                        app.UseXaxisCheckBoxValueChanged(struct('Source',app.UseXaxisCheckBox));
                        app.UseYaxisCheckBoxValueChanged(struct('Source',app.UseYaxisCheckBox));
                        app.UseZaxisCheckBoxValueChanged(struct('Source',app.UseZaxisCheckBox));
                        app.UsePhiCheckBoxValueChanged(struct('Source',app.UsePhiCheckBox));
                    case 'Set 2'
                        distributeParametersTab2(app, parStruct);
                        app.UseXaxisCheckBoxValueChanged(struct('Source',app.UseXaxisCheckBox_2));
                        app.UseYaxisCheckBoxValueChanged(struct('Source',app.UseYaxisCheckBox_2));
                        app.UseZaxisCheckBoxValueChanged(struct('Source',app.UseZaxisCheckBox_2));
                        app.UsePhiCheckBoxValueChanged(struct('Source',app.UsePhiCheckBox_2));
                    otherwise
                        return
                end

            end
        end

        % Menu selected function: GeneratetemplatejsonMenu
        function GeneratetemplatejsonMenuSelected(app, event)
            [file,loc] = uiputfile('*.json','Select template file path','parametersTemplate.json');
            figure(app.UIFigure)
            if file == 0
                % User clicked on cancel
                return
            else
                parStruct = app.parseParameterSet1;
                jsonText = jsonencode(parStruct,"PrettyPrint",true);

                filepath = fullfile(loc,file);
                writelines(jsonText, filepath);
            end

        end

        % Button pushed function: ResetinternalstatesButton
        function ResetinternalstatesButtonPushed(app, event)
            if app.Connected && ~app.DemoMode
                state_reset_ok = resetInternalStates(app);
            end
        end

        % Value changed function: ReservedRAMEditField
        function ReservedRAMEditFieldValueChanged(app, event)
            newValue = app.ReservedRAMEditField.Value;
            oldValue = app.maxDMABytes / 1024 / 1024;
            
            msg = sprintf('Are you sure you want to change Reserved RAM from %.2f MB to %.2f MB? This value has to correspond to the hardware configuration!', ...
                          oldValue, newValue);
            selection = uiconfirm(app.UIFigure, msg, 'Confirm RAM Change', ...
                'Options', {'OK', 'Cancel'}, ...
                'DefaultOption', 'Cancel', ...
                'Icon', 'question');
            
            % 5. Handle the user's choice
            switch selection
                case 'OK'
                    % Update the underlying property with the new value (converted back to bytes)
                    app.maxDMABytes = newValue * 1024 * 1024;
                case 'Cancel'
                    % Revert the edit field to the previous value
                    app.ReservedRAMEditField.Value = oldValue;
            end
        end

        % Callback function
        function RefreshplotButtonPushed(app, event)
            app.updateDataPlot;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ConnectTab = uitab(app.TabGroup);
            app.ConnectTab.Title = 'Connect';
            app.GridLayout2 = uigridlayout(app.ConnectTab);
            app.ConnectStateButton = uibutton(app.GridLayout2, 'state');
            app.ConnectStateButton.ValueChangedFcn = createCallbackFcn(app, @ConnectStateButtonValueChanged, true);
            app.ConnectStateButton.Text = 'Click to connect';
            app.RedPitayaIPAddressEditFieldLabel = uilabel(app.GridLayout2);
            app.RedPitayaIPAddressEditFieldLabel.Text = 'Red Pitaya IP Address';
            app.RedPitayaIPAddressEditField = uieditfield(app.GridLayout2, 'text');
            app.RedPitayaIPAddressEditField.Value = '169.254.88.165';
            app.DemomodenohardwareconnectedSwitchLabel = uilabel(app.GridLayout2);
            app.DemomodenohardwareconnectedSwitchLabel.Text = 'Demo mode (no hardware connected)';
            app.DemoModeSwitch = uiswitch(app.GridLayout2, 'slider');
            app.DemoModeSwitch.ValueChangedFcn = createCallbackFcn(app, @DemoModeSwitchValueChanged, true);
            app.ResetinternalstatesButton = uibutton(app.GridLayout2, 'push');
            app.ResetinternalstatesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetinternalstatesButtonPushed, true);
            app.ResetinternalstatesButton.Enable = 'off';
            app.ResetinternalstatesButton.Text = 'Reset internal states';
            app.DataAcquisitionTab = uitab(app.TabGroup);
            app.DataAcquisitionTab.Title = 'Data Acquisition';
            app.GridLayout6 = uigridlayout(app.DataAcquisitionTab);
            app.DataAxes = uiaxes(app.GridLayout6);
            xlabel(app.DataAxes, 'X')
            ylabel(app.DataAxes, 'Y')
            zlabel(app.DataAxes, 'Z')
            app.NumberofframestoplotEditFieldLabel = uilabel(app.GridLayout6);
            app.NumberofframestoplotEditFieldLabel.Text = 'Number of frames to plot';
            app.NumberofframestoplotEditField = uieditfield(app.GridLayout6, 'numeric');
            app.NumberofframestoplotEditField.Limits = [16 Inf];
            app.NumberofframestoplotEditField.Value = 1000000;
            app.RecorddataButton = uibutton(app.GridLayout6, 'push');
            app.RecorddataButton.ButtonPushedFcn = createCallbackFcn(app, @RecorddataButtonPushed, true);
            app.RecorddataButton.Enable = 'off';
            app.RecorddataButton.Text = 'Record data';
            app.PlotStyleSwitchLabel = uilabel(app.GridLayout6);
            app.PlotStyleSwitchLabel.Text = 'Plot Style';
            app.PlotStyleSwitch = uiswitch(app.GridLayout6, 'slider');
            app.PlotStyleSwitch.Items = {'Time', 'PSD'};
            app.PlotStyleSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotStyleSwitchValueChanged, true);
            app.PlotStyleSwitch.Value = 'Time';
            app.OutputfilepathEditFieldLabel = uilabel(app.GridLayout6);
            app.OutputfilepathEditFieldLabel.Text = 'Output file path';
            app.OutputfilepathEditField = uieditfield(app.GridLayout6, 'text');
            app.SavematfileButton = uibutton(app.GridLayout6, 'push');
            app.SavematfileButton.ButtonPushedFcn = createCallbackFcn(app, @SavematfileButtonPushed, true);
            app.SavematfileButton.Text = 'Save mat file';
            app.NumberofframestorecordEditFieldLabel = uilabel(app.GridLayout6);
            app.NumberofframestorecordEditFieldLabel.Text = 'Number of frames to record';
            app.NumberofframestorecordEditField = uieditfield(app.GridLayout6, 'numeric');
            app.NumberofframestorecordEditField.Limits = [16 Inf];
            app.NumberofframestorecordEditField.Value = 1000000;
            app.SignalSelectorUITable = uitable(app.GridLayout6);
            app.SignalSelectorUITable.ColumnName = {'ID'; 'Record'; 'Plot'};
            app.SignalSelectorUITable.RowName = {};
            app.SignalSelectorUITable.ColumnEditable = [false true true];
            app.SignalSelectorUITable.CellEditCallback = createCallbackFcn(app, @SignalSelectorUITableCellEdit, true);
            app.RecordingdecimationNx64nsLabel = uilabel(app.GridLayout6);
            app.RecordingdecimationNx64nsLabel.Text = 'Recording decimation [Nx64ns]';
            app.RecordingDecimationEditField = uieditfield(app.GridLayout6, 'numeric');
            app.RecordingDecimationEditField.Limits = [1 Inf];
            app.RecordingDecimationEditField.Value = 1;
            app.PSDaveragingEditFieldLabel = uilabel(app.GridLayout6);
            app.PSDaveragingEditFieldLabel.Enable = 'off';
            app.PSDaveragingEditFieldLabel.Text = 'PSD averaging';
            app.PSDaveragingEditField = uieditfield(app.GridLayout6, 'numeric');
            app.PSDaveragingEditField.Limits = [1 Inf];
            app.PSDaveragingEditField.Enable = 'off';
            app.PSDaveragingEditField.Value = 10;
            app.ReservedRAMMBEditFieldLabel = uilabel(app.GridLayout6);
            app.ReservedRAMMBEditFieldLabel.Text = 'Reserved RAM [MB]';
            app.ReservedRAMEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ReservedRAMEditField.ValueChangedFcn = createCallbackFcn(app, @ReservedRAMEditFieldValueChanged, true);
            app.ReservedRAMEditField.Value = 256;
            app.ModelconfigurationTab = uitab(app.TabGroup);
            app.ModelconfigurationTab.Title = 'Model configuration';
            app.GridLayout8 = uigridlayout(app.ModelconfigurationTab);
            app.TabGroup2 = uitabgroup(app.GridLayout8);
            app.Parameterset1Tab = uitab(app.TabGroup2);
            app.Parameterset1Tab.Title = 'Parameter set 1';
            app.Parameterset1Tab.Tag = 'ParametersTab1';
            app.GridLayout9 = uigridlayout(app.Parameterset1Tab);
            app.XfrequencyEditFieldLabel = uilabel(app.GridLayout9);
            app.XfrequencyEditFieldLabel.Text = 'X frequency [Hz]';
            app.XfrequencyEditField = uieditfield(app.GridLayout9, 'numeric');
            app.XfrequencyEditField.Value = 50000;
            app.YfrequencyEditFieldLabel = uilabel(app.GridLayout9);
            app.YfrequencyEditFieldLabel.Text = 'Y frequency [Hz]';
            app.YfrequencyEditField = uieditfield(app.GridLayout9, 'numeric');
            app.YfrequencyEditField.Value = 80000;
            app.ZfrequencyEditFieldLabel = uilabel(app.GridLayout9);
            app.ZfrequencyEditFieldLabel.Text = 'Z frequency [Hz]';
            app.ZfrequencyEditField = uieditfield(app.GridLayout9, 'numeric');
            app.ZfrequencyEditField.Value = 100000;
            app.PhiTimeConstEditFieldLabel = uilabel(app.GridLayout9);
            app.PhiTimeConstEditFieldLabel.Enable = 'off';
            app.PhiTimeConstEditFieldLabel.Text = 'Phi time constant [s]';
            app.PhiTimeConstEditField = uieditfield(app.GridLayout9, 'numeric');
            app.PhiTimeConstEditField.Enable = 'off';
            app.PhiTimeConstEditField.Value = 10000;
            app.XdampingEditFieldLabel = uilabel(app.GridLayout9);
            app.XdampingEditFieldLabel.Text = 'X damping [Hz]';
            app.XdampingEditField = uieditfield(app.GridLayout9, 'numeric');
            app.XdampingEditField.Value = 1000;
            app.YdampingEditFieldLabel = uilabel(app.GridLayout9);
            app.YdampingEditFieldLabel.Text = 'Y damping [Hz]';
            app.YdampingEditField = uieditfield(app.GridLayout9, 'numeric');
            app.YdampingEditField.Value = 1000;
            app.ZdampingEditFieldLabel = uilabel(app.GridLayout9);
            app.ZdampingEditFieldLabel.Text = 'Z damping [Hz]';
            app.ZdampingEditField = uieditfield(app.GridLayout9, 'numeric');
            app.ZdampingEditField.Value = 1000;
            app.XprocessnoiseEditFieldLabel = uilabel(app.GridLayout9);
            app.XprocessnoiseEditFieldLabel.Text = 'X process noise [N^2/Hz]';
            app.XprocessnoiseEditField = uieditfield(app.GridLayout9, 'numeric');
            app.XprocessnoiseEditField.Value = 3.2e-34;
            app.YprocessnoiseEditFieldLabel = uilabel(app.GridLayout9);
            app.YprocessnoiseEditFieldLabel.Text = 'Y process noise [N^2/Hz]';
            app.YprocessnoiseEditField = uieditfield(app.GridLayout9, 'numeric');
            app.YprocessnoiseEditField.Value = 3.2e-34;
            app.ZprocessnoiseEditFieldLabel = uilabel(app.GridLayout9);
            app.ZprocessnoiseEditFieldLabel.Text = 'Z process noise [N^2/Hz]';
            app.ZprocessnoiseEditField = uieditfield(app.GridLayout9, 'numeric');
            app.ZprocessnoiseEditField.Value = 3.2e-34;
            app.PhiprocessnoiseEditFieldLabel = uilabel(app.GridLayout9);
            app.PhiprocessnoiseEditFieldLabel.Enable = 'off';
            app.PhiprocessnoiseEditFieldLabel.Text = 'Phi process noise [m^2/Hz]';
            app.PhiprocessnoiseEditField = uieditfield(app.GridLayout9, 'numeric');
            app.PhiprocessnoiseEditField.Enable = 'off';
            app.PhiprocessnoiseEditField.Value = 1e-06;
            app.ParticlemassEditFieldLabel = uilabel(app.GridLayout9);
            app.ParticlemassEditFieldLabel.Text = 'Particle mass [kg]';
            app.ParticlemassEditField = uieditfield(app.GridLayout9, 'numeric');
            app.ParticlemassEditField.Value = 5e-17;
            app.TabGroup3 = uitabgroup(app.GridLayout9);
            app.DetectionActuationTab = uitab(app.TabGroup3);
            app.DetectionActuationTab.Title = 'Detection + Actuation';
            app.GridLayout10 = uigridlayout(app.DetectionActuationTab);
            app.CVmUITable = uitable(app.GridLayout10);
            app.CVmUITable.ColumnName = {'X'; 'dX'; 'Y'; 'dY'; 'Z'; 'dZ'; 'Phi'};
            app.CVmUITable.RowName = {'Coeff of [-] in ADC1 [V/m]'; 'Coeff of [-] in ADC2 [V/m]'};
            app.CNVUITable = uitable(app.GridLayout10);
            app.CNVUITable.ColumnName = {'X'; 'dX'; 'Y'; 'dY'; 'Z'; 'dZ'; 'Phi'};
            app.CNVUITable.RowName = {'Coupling of [-] to DAC1 [N/V]'; 'Coupling of [-] in DAC2 [N/V]'};
            app.MeasnoisematrixUITable = uitable(app.GridLayout10);
            app.MeasnoisematrixUITable.ColumnName = {'V1'; 'V2'};
            app.MeasnoisematrixUITable.RowName = {'Noise variance in ADC1 [V^2/Hz]'; 'Noise variance in ADC2 [V^2/Hz]'};
            app.DetectioncalibrationcoefficientsVmorVsmLabel = uilabel(app.GridLayout10);
            app.DetectioncalibrationcoefficientsVmorVsmLabel.Text = 'Detection calibration coefficients [V/m] or [V.s/m]';
            app.MeasurementnoisevariancesV2HzLabel = uilabel(app.GridLayout10);
            app.MeasurementnoisevariancesV2HzLabel.Text = 'Measurement noise variances [V^2/Hz]';
            app.ActuatorcouplingcoefficientsNVorkgmsVLabel = uilabel(app.GridLayout10);
            app.ActuatorcouplingcoefficientsNVorkgmsVLabel.Text = 'Actuator coupling coefficients [N/V] or [kg.m/s.V]';
            app.TuningvariablesTab = uitab(app.TabGroup3);
            app.TuningvariablesTab.Title = 'Tuning variables';
            app.GridLayout11 = uigridlayout(app.TuningvariablesTab);
            app.UseXaxisCheckBox = uicheckbox(app.GridLayout11);
            app.UseXaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseXaxisCheckBoxValueChanged, true);
            app.UseXaxisCheckBox.Text = 'Use X axis';
            app.UseXaxisCheckBox.Value = true;
            app.UseYaxisCheckBox = uicheckbox(app.GridLayout11);
            app.UseYaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseYaxisCheckBoxValueChanged, true);
            app.UseYaxisCheckBox.Text = 'Use Y axis';
            app.UseYaxisCheckBox.Value = true;
            app.UseZaxisCheckBox = uicheckbox(app.GridLayout11);
            app.UseZaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseZaxisCheckBoxValueChanged, true);
            app.UseZaxisCheckBox.Text = 'Use Z axis';
            app.UseZaxisCheckBox.Value = true;
            app.UsePhiCheckBox = uicheckbox(app.GridLayout11);
            app.UsePhiCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsePhiCheckBoxValueChanged, true);
            app.UsePhiCheckBox.Text = 'Use Phi';
            app.RelativeStateWeightUITable = uitable(app.GridLayout11);
            app.RelativeStateWeightUITable.ColumnName = {'X'; 'dX'; 'Y'; 'dY'; 'Z'; 'dZ'; 'Phi'};
            app.RelativeStateWeightUITable.RowName = {'X*[-]'; 'dX*[-]'; 'Y*[-]'; 'dY*[-]'; 'Z*[-]'; 'dZ*[-]'; 'Phi*[-]'};
            app.RecalculateLQRButton = uibutton(app.GridLayout11, 'push');
            app.RecalculateLQRButton.ButtonPushedFcn = createCallbackFcn(app, @RecalculateLQRButtonPushed, true);
            app.RecalculateLQRButton.Text = 'Recalculate LQR';
            app.FeedbackweighingmatrixsqrtR_LQR1VLabel = uilabel(app.GridLayout11);
            app.FeedbackweighingmatrixsqrtR_LQR1VLabel.Text = 'Feedback-weighing matrix sqrt(R_LQR) [1/V]';
            app.RelativeweighingofstatesinQ_LQRdiagonesstandardenergycostLabel = uilabel(app.GridLayout11);
            app.RelativeweighingofstatesinQ_LQRdiagonesstandardenergycostLabel.Text = 'Relative weighing of states in Q_LQR [-] (diag. ones - standard energy cost) ';
            app.FbGainUITable = uitable(app.GridLayout11);
            app.FbGainUITable.ColumnName = {'DAC1'; 'DAC2'};
            app.FbGainUITable.RowName = {'DAC1*[-]'; 'DAC2*[-]'};
            app.InternalmodelformatDropDown = uidropdown(app.GridLayout11);
            app.InternalmodelformatDropDown.Items = {'Standard', 'Transformed standard', 'Closed-loop', 'Transformed closed-loop'};
            app.InternalmodelformatDropDown.ItemsData = {'[0,0]', '[0,1]', '[1,0]', '[1,1]'};
            app.InternalmodelformatDropDown.Value = '[0,0]';
            app.InternalmodelformatDropDownLabel = uilabel(app.GridLayout11);
            app.InternalmodelformatDropDownLabel.Text = 'Internal model format';
            app.FeedbackgainTab = uitab(app.TabGroup3);
            app.FeedbackgainTab.Title = 'Feedback gain';
            app.GridLayout12 = uigridlayout(app.FeedbackgainTab);
            app.KLQRdispUITable = uitable(app.GridLayout12);
            app.KLQRdispUITable.ColumnName = {'X'; 'dX'; 'Y'; 'dY'; 'Z'; 'dZ'; 'Phi'};
            app.KLQRdispUITable.RowName = {'DAC1 = entry*[-]'; 'DAC2 = entry*[-]'};
            app.FbStdUITable = uitable(app.GridLayout12);
            app.FbStdUITable.ColumnName = {'DAC1'; 'DAC2'};
            app.FbStdUITable.RowName = {'DAC1*[-]'; 'DAC2*[-]'};
            app.KLQRmatrixentriesLabel = uilabel(app.GridLayout12);
            app.KLQRmatrixentriesLabel.Text = 'K LQR matrix entries:';
            app.Estimatedoutputvoltagestandarddeviation3VLabel = uilabel(app.GridLayout12);
            app.Estimatedoutputvoltagestandarddeviation3VLabel.Text = 'Estimated output voltage standard deviation * 3 [V]:';
            app.IOcalibrationTab = uitab(app.TabGroup3);
            app.IOcalibrationTab.Title = 'IO calibration';
            app.GridLayout14 = uigridlayout(app.IOcalibrationTab);
            app.DACCalibUITable = uitable(app.GridLayout14);
            app.DACCalibUITable.ColumnName = {'Gain correction [-]'; 'Offset [V]'};
            app.DACCalibUITable.RowName = {'DAC1*[-]'; 'DAC2*[-]'};
            app.ADCCalibUITable = uitable(app.GridLayout14);
            app.ADCCalibUITable.ColumnName = {'Gain correction [-]'; 'Offset [V]'};
            app.ADCCalibUITable.RowName = {'ADC1*[-]'; 'ADC2*[-]'};
            app.OutputDACcalibrationLabel = uilabel(app.GridLayout14);
            app.OutputDACcalibrationLabel.Text = 'Output (DAC) calibration';
            app.InputADCcalibrationLabel = uilabel(app.GridLayout14);
            app.InputADCcalibrationLabel.Text = 'Input (ADC) calibration';
            app.AdvancedTab = uitab(app.TabGroup3);
            app.AdvancedTab.Title = 'Advanced';
            app.GridLayout16 = uigridlayout(app.AdvancedTab);
            app.TransformStateSpaceCheckBox = uicheckbox(app.GridLayout16);
            app.TransformStateSpaceCheckBox.Text = 'Transform the state space to match the expected variance';
            app.TransformStateSpaceCheckBox.Value = true;
            app.LimitTransformCheckBox = uicheckbox(app.GridLayout16);
            app.LimitTransformCheckBox.Text = 'Limit the transformation to internal datatypes';
            app.LimitTransformCheckBox.Value = true;
            app.AdvancedTabLabel = uilabel(app.GridLayout16);
            app.AdvancedTabLabel.Text = 'Settings in this tab allow the user to fine-tune the numerical behaviour of the estimator. For most use-cases, you don''t need to change any of these settings!';
            app.UndoTransformForPlotCheckBox = uicheckbox(app.GridLayout16);
            app.UndoTransformForPlotCheckBox.Text = 'Undo the transformation for data plotting';
            app.UndoTransformForPlotCheckBox.Value = true;
            app.TargetstatestandarddeviationLabel = uilabel(app.GridLayout16);
            app.TargetstatestandarddeviationLabel.Text = 'Target state standard deviation [-]';
            app.TargetstatestandarddeviationEditField = uieditfield(app.GridLayout16, 'numeric');
            app.TargetstatestandarddeviationEditField.Limits = [0 Inf];
            app.TargetstatestandarddeviationEditField.Value = 0.001;
            app.Parameterset2Tab = uitab(app.TabGroup2);
            app.Parameterset2Tab.Title = 'Parameter set 2';
            app.Parameterset2Tab.Tag = 'ParametersTab2';
            app.GridLayout9_2 = uigridlayout(app.Parameterset2Tab);
            app.XfrequencyEditFieldLabel_2 = uilabel(app.GridLayout9_2);
            app.XfrequencyEditFieldLabel_2.Text = 'X frequency [Hz]';
            app.XfrequencyEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
            app.XfrequencyEditField_2.Value = 50000;
            app.YfrequencyEditFieldLabel_2 = uilabel(app.GridLayout9_2);
            app.YfrequencyEditFieldLabel_2.Text = 'Y frequency [Hz]';
            app.YfrequencyEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
            app.YfrequencyEditField_2.Value = 80000;
            app.ZfrequencyEditFieldLabel_2 = uilabel(app.GridLayout9_2);
            app.ZfrequencyEditFieldLabel_2.Text = 'Z frequency [Hz]';
            app.ZfrequencyEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
            app.ZfrequencyEditField_2.Value = 100000;
            app.PhiTimeConstEditFieldLabel_2 = uilabel(app.GridLayout9_2);
            app.PhiTimeConstEditFieldLabel_2.Enable = 'off';
            app.PhiTimeConstEditFieldLabel_2.Text = 'Phi time constant [s]';
            app.PhiTimeConstEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
            app.PhiTimeConstEditField_2.Enable = 'off';
            app.PhiTimeConstEditField_2.Value = 10000;
            app.XdampingEditFieldLabel_2 = uilabel(app.GridLayout9_2);
            app.XdampingEditFieldLabel_2.Text = 'X damping [Hz]';
            app.XdampingEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
            app.XdampingEditField_2.Value = 1000;
            app.YdampingEditFieldLabel_2 = uilabel(app.GridLayout9_2);
            app.YdampingEditFieldLabel_2.Text = 'Y damping [Hz]';
            app.YdampingEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
            app.YdampingEditField_2.Value = 1000;
            app.ZdampingEditFieldLabel_2 = uilabel(app.GridLayout9_2);
            app.ZdampingEditFieldLabel_2.Text = 'Z damping [Hz]';
            app.ZdampingEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
            app.ZdampingEditField_2.Value = 1000;
            app.XprocessnoiseEditFieldLabel_2 = uilabel(app.GridLayout9_2);
            app.XprocessnoiseEditFieldLabel_2.Text = 'X process noise [N^2/Hz]';
            app.XprocessnoiseEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
            app.XprocessnoiseEditField_2.Value = 3.2e-34;
            app.YprocessnoiseEditFieldLabel_2 = uilabel(app.GridLayout9_2);
            app.YprocessnoiseEditFieldLabel_2.Text = 'Y process noise [N^2/Hz]';
            app.YprocessnoiseEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
            app.YprocessnoiseEditField_2.Value = 3.2e-34;
            app.ZprocessnoiseEditFieldLabel_2 = uilabel(app.GridLayout9_2);
            app.ZprocessnoiseEditFieldLabel_2.Text = 'Z process noise [N^2/Hz]';
            app.ZprocessnoiseEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
            app.ZprocessnoiseEditField_2.Value = 3.2e-34;
            app.PhiprocessnoiseEditFieldLabel_2 = uilabel(app.GridLayout9_2);
            app.PhiprocessnoiseEditFieldLabel_2.Enable = 'off';
            app.PhiprocessnoiseEditFieldLabel_2.Text = 'Phi process noise [m^2/Hz]';
            app.PhiprocessnoiseEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
            app.PhiprocessnoiseEditField_2.Enable = 'off';
            app.PhiprocessnoiseEditField_2.Value = 1e-06;
            app.ParticlemassEditFieldLabel_2 = uilabel(app.GridLayout9_2);
            app.ParticlemassEditFieldLabel_2.Text = 'Particle mass [kg]';
            app.ParticlemassEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
            app.ParticlemassEditField_2.Value = 5e-17;
            app.TabGroup3_2 = uitabgroup(app.GridLayout9_2);
            app.DetectionActuationTab_2 = uitab(app.TabGroup3_2);
            app.DetectionActuationTab_2.Title = 'Detection + Actuation';
            app.GridLayout10_2 = uigridlayout(app.DetectionActuationTab_2);
            app.CVmUITable_2 = uitable(app.GridLayout10_2);
            app.CVmUITable_2.ColumnName = {'X'; 'dX'; 'Y'; 'dY'; 'Z'; 'dZ'; 'Phi'};
            app.CVmUITable_2.RowName = {'Coeff of [-] in ADC1 [V/m]'; 'Coeff of [-] in ADC2 [V/m]'};
            app.CNVUITable_2 = uitable(app.GridLayout10_2);
            app.CNVUITable_2.ColumnName = {'X'; 'dX'; 'Y'; 'dY'; 'Z'; 'dZ'; 'Phi'};
            app.CNVUITable_2.RowName = {'Coupling of [-] to DAC1 [N/V]'; 'Coupling of [-] in DAC2 [N/V]'};
            app.MeasnoisematrixUITable_2 = uitable(app.GridLayout10_2);
            app.MeasnoisematrixUITable_2.ColumnName = {'V1'; 'V2'};
            app.MeasnoisematrixUITable_2.RowName = {'Noise variance in ADC1 [V^2/Hz]'; 'Noise variance in ADC2 [V^2/Hz]'};
            app.DetectioncalibrationcoefficientsVmorVsmLabel_2 = uilabel(app.GridLayout10_2);
            app.DetectioncalibrationcoefficientsVmorVsmLabel_2.Text = 'Detection calibration coefficients [V/m] or [V.s/m]';
            app.MeasurementnoisevariancesV2HzLabel_2 = uilabel(app.GridLayout10_2);
            app.MeasurementnoisevariancesV2HzLabel_2.Text = 'Measurement noise variances [V^2/Hz]';
            app.ActuatorcouplingcoefficientsNVorkgmsVLabel_2 = uilabel(app.GridLayout10_2);
            app.ActuatorcouplingcoefficientsNVorkgmsVLabel_2.Text = 'Actuator coupling coefficients [N/V] or [kg.m/s.V]';
            app.TuningvariablesTab_2 = uitab(app.TabGroup3_2);
            app.TuningvariablesTab_2.Title = 'Tuning variables';
            app.GridLayout11_2 = uigridlayout(app.TuningvariablesTab_2);
            app.UseXaxisCheckBox_2 = uicheckbox(app.GridLayout11_2);
            app.UseXaxisCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @UseXaxisCheckBoxValueChanged, true);
            app.UseXaxisCheckBox_2.Text = 'Use X axis';
            app.UseXaxisCheckBox_2.Value = true;
            app.UseYaxisCheckBox_2 = uicheckbox(app.GridLayout11_2);
            app.UseYaxisCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @UseYaxisCheckBoxValueChanged, true);
            app.UseYaxisCheckBox_2.Text = 'Use Y axis';
            app.UseYaxisCheckBox_2.Value = true;
            app.UseZaxisCheckBox_2 = uicheckbox(app.GridLayout11_2);
            app.UseZaxisCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @UseZaxisCheckBoxValueChanged, true);
            app.UseZaxisCheckBox_2.Text = 'Use Z axis';
            app.UseZaxisCheckBox_2.Value = true;
            app.UsePhiCheckBox_2 = uicheckbox(app.GridLayout11_2);
            app.UsePhiCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @UsePhiCheckBoxValueChanged, true);
            app.UsePhiCheckBox_2.Text = 'Use Phi';
            app.RelativeStateWeightUITable_2 = uitable(app.GridLayout11_2);
            app.RelativeStateWeightUITable_2.ColumnName = {'X'; 'dX'; 'Y'; 'dY'; 'Z'; 'dZ'; 'Phi'};
            app.RelativeStateWeightUITable_2.RowName = {'X*[-]'; 'dX*[-]'; 'Y*[-]'; 'dY*[-]'; 'Z*[-]'; 'dZ*[-]'; 'Phi*[-]'};
            app.RecalculateLQRButton_2 = uibutton(app.GridLayout11_2, 'push');
            app.RecalculateLQRButton_2.ButtonPushedFcn = createCallbackFcn(app, @RecalculateLQRButtonPushed, true);
            app.RecalculateLQRButton_2.Text = 'Recalculate LQR';
            app.FeedbackweighingmatrixsqrtR_LQR1VLabel_2 = uilabel(app.GridLayout11_2);
            app.FeedbackweighingmatrixsqrtR_LQR1VLabel_2.Text = 'Feedback-weighing matrix sqrt(R_LQR) [1/V]';
            app.Label = uilabel(app.GridLayout11_2);
            app.Label.Text = 'Relative weighing of states in Q_LQR [-] (diag. ones - standard energy cost) ';
            app.FbGainUITable_2 = uitable(app.GridLayout11_2);
            app.FbGainUITable_2.ColumnName = {'DAC1'; 'DAC2'};
            app.FbGainUITable_2.RowName = {'DAC1*[-]'; 'DAC2*[-]'};
            app.InternalmodelformatDropDown_2 = uidropdown(app.GridLayout11_2);
            app.InternalmodelformatDropDown_2.Items = {'Standard', 'Transformed standard', 'Closed-loop', 'Transformed closed-loop'};
            app.InternalmodelformatDropDown_2.ItemsData = {'[0,0]', '[0,1]', '[1,0]', '[1,1]'};
            app.InternalmodelformatDropDown_2.Value = '[0,0]';
            app.InternalmodelformatDropDown_2Label = uilabel(app.GridLayout11_2);
            app.InternalmodelformatDropDown_2Label.Text = 'Internal model format';
            app.FeedbackgainTab_2 = uitab(app.TabGroup3_2);
            app.FeedbackgainTab_2.Title = 'Feedback gain';
            app.GridLayout12_2 = uigridlayout(app.FeedbackgainTab_2);
            app.KLQRdispUITable_2 = uitable(app.GridLayout12_2);
            app.KLQRdispUITable_2.ColumnName = {'X'; 'dX'; 'Y'; 'dY'; 'Z'; 'dZ'; 'Phi'};
            app.KLQRdispUITable_2.RowName = {'DAC1 = entry*[-]'; 'DAC2 = entry*[-]'};
            app.FbStdUITable_2 = uitable(app.GridLayout12_2);
            app.FbStdUITable_2.ColumnName = {'DAC1'; 'DAC2'};
            app.FbStdUITable_2.RowName = {'DAC1*[-]'; 'DAC2*[-]'};
            app.KLQRmatrixentriesLabel_2 = uilabel(app.GridLayout12_2);
            app.KLQRmatrixentriesLabel_2.Text = 'K LQR matrix entries:';
            app.Estimatedoutputvoltagestandarddeviation3VLabel_2 = uilabel(app.GridLayout12_2);
            app.Estimatedoutputvoltagestandarddeviation3VLabel_2.Text = 'Estimated output voltage standard deviation * 3 [V]:';
            app.IOcalibrationTab_2 = uitab(app.TabGroup3_2);
            app.IOcalibrationTab_2.Title = 'IO calibration';
            app.GridLayout14_2 = uigridlayout(app.IOcalibrationTab_2);
            app.DACCalibUITable_2 = uitable(app.GridLayout14_2);
            app.DACCalibUITable_2.ColumnName = {'Gain correction [-]'; 'Offset [V]'};
            app.DACCalibUITable_2.RowName = {'DAC1*[-]'; 'DAC2*[-]'};
            app.ADCCalibUITable_2 = uitable(app.GridLayout14_2);
            app.ADCCalibUITable_2.ColumnName = {'Gain correction [-]'; 'Offset [V]'};
            app.ADCCalibUITable_2.RowName = {'ADC1*[-]'; 'ADC2*[-]'};
            app.OutputDACcalibrationLabel_2 = uilabel(app.GridLayout14_2);
            app.OutputDACcalibrationLabel_2.Text = 'Output (DAC) calibration';
            app.InputADCcalibrationLabel_2 = uilabel(app.GridLayout14_2);
            app.InputADCcalibrationLabel_2.Text = 'Input (ADC) calibration';
            app.AdvancedTab_2 = uitab(app.TabGroup3_2);
            app.AdvancedTab_2.Title = 'Advanced';
            app.GridLayout16_2 = uigridlayout(app.AdvancedTab_2);
            app.TransformStateSpaceCheckBox_2 = uicheckbox(app.GridLayout16_2);
            app.TransformStateSpaceCheckBox_2.Text = 'Transform the state space to match the expected variance';
            app.TransformStateSpaceCheckBox_2.Value = true;
            app.LimitTransformCheckBox_2 = uicheckbox(app.GridLayout16_2);
            app.LimitTransformCheckBox_2.Text = 'Limit the transformation to internal datatypes';
            app.LimitTransformCheckBox_2.Value = true;
            app.AdvancedTabLabel_2 = uilabel(app.GridLayout16_2);
            app.AdvancedTabLabel_2.Text = 'Settings in this tab allow the user to fine-tune the numerical behaviour of the estimator. For most use-cases, you don''t need to change any of these settings!';
            app.UndoTransformForPlotCheckBox_2 = uicheckbox(app.GridLayout16_2);
            app.UndoTransformForPlotCheckBox_2.Text = 'Undo the transformation for data plotting';
            app.UndoTransformForPlotCheckBox_2.Value = true;
            app.TargetstatestandarddeviationEditField_2Label = uilabel(app.GridLayout16_2);
            app.TargetstatestandarddeviationEditField_2Label.Text = 'Target state standard deviation [-]';
            app.TargetstatestandarddeviationEditField_2 = uieditfield(app.GridLayout16_2, 'numeric');
            app.TargetstatestandarddeviationEditField_2.Limits = [0 Inf];
            app.TargetstatestandarddeviationEditField_2.Value = 0.001;
            app.LoadparametersButton = uibutton(app.GridLayout8, 'push');
            app.LoadparametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadparametersButtonPushed, true);
            app.LoadparametersButton.Text = 'Load parameters';
            app.ImportparametersButton = uibutton(app.GridLayout8, 'push');
            app.ImportparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ImportparametersButtonPushed, true);
            app.ImportparametersButton.Text = 'Import parameters';
            app.SaveparametersButton = uibutton(app.GridLayout8, 'push');
            app.SaveparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparametersButtonPushed, true);
            app.SaveparametersButton.Text = 'Save parameters';
            app.PushparametersButton = uibutton(app.GridLayout8, 'push');
            app.PushparametersButton.ButtonPushedFcn = createCallbackFcn(app, @PushparametersButtonPushed, true);
            app.PushparametersButton.Enable = 'off';
            app.PushparametersButton.Text = 'Push parameters';
            app.UserGuideTab = uitab(app.TabGroup);
            app.UserGuideTab.Title = 'User Guide';
            app.GridLayout15 = uigridlayout(app.UserGuideTab);
            app.DocsHTML = uihtml(app.GridLayout15);
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.GridLayout13 = uigridlayout(app.LogTab);
            app.LogDisplay = mlog.view.LogDisplay(app.GridLayout13);
            app.LogDisplay.LogName = "LQG-FP7";
            app.LogDisplay.LowerDisplayThreshold = 'MESSAGE';
            app.IOcontrolPanel = uipanel(app.GridLayout);
            app.IOcontrolPanel.Title = 'IO control';
            app.GridLayout7 = uigridlayout(app.IOcontrolPanel);
            app.EnablefeedbackLabel = uilabel(app.GridLayout7);
            app.EnablefeedbackLabel.Enable = 'off';
            app.EnablefeedbackLabel.Text = {'Enable '; 'feedback'};
            app.EnablefeedbackSwitch = uiswitch(app.GridLayout7, 'toggle');
            app.EnablefeedbackSwitch.ValueChangedFcn = createCallbackFcn(app, @EnablefeedbackSwitchValueChanged, true);
            app.EnablefeedbackSwitch.Enable = 'off';
            app.ParametersetselectSwitchLabel = uilabel(app.GridLayout7);
            app.ParametersetselectSwitchLabel.Enable = 'off';
            app.ParametersetselectSwitchLabel.Text = {'Parameter '; 'set select'};
            app.ParametersetselectSwitch = uiswitch(app.GridLayout7, 'toggle');
            app.ParametersetselectSwitch.Items = {'Set 1', 'Set 2'};
            app.ParametersetselectSwitch.ValueChangedFcn = createCallbackFcn(app, @ParametersetselectSwitchValueChanged, true);
            app.ParametersetselectSwitch.Enable = 'off';
            app.ParametersetselectSwitch.Value = 'Set 1';
            app.MatrixUITableContextMenu = uicontextmenu(app.UIFigure);
            app.MultiplyTableByConstantMenu = uimenu(app.MatrixUITableContextMenu);
            app.MultiplyTableByConstantMenu.MenuSelectedFcn = createCallbackFcn(app, @MultiplyTableByConstantMenuSelected, true);
            app.MultiplyTableByConstantMenu.Text = 'Multiply the entire table by a constant';
            % Assign app.MatrixUITableContextMenu
            app.CVmUITable.ContextMenu = app.MatrixUITableContextMenu;
            app.CNVUITable.ContextMenu = app.MatrixUITableContextMenu;
            app.MeasnoisematrixUITable.ContextMenu = app.MatrixUITableContextMenu;
            app.RelativeStateWeightUITable.ContextMenu = app.MatrixUITableContextMenu;
            app.FbGainUITable.ContextMenu = app.MatrixUITableContextMenu;
            app.CVmUITable_2.ContextMenu = app.MatrixUITableContextMenu;
            app.CNVUITable_2.ContextMenu = app.MatrixUITableContextMenu;
            app.MeasnoisematrixUITable_2.ContextMenu = app.MatrixUITableContextMenu;
            app.RelativeStateWeightUITable_2.ContextMenu = app.MatrixUITableContextMenu;
            app.FbGainUITable_2.ContextMenu = app.MatrixUITableContextMenu;
            app.DataPlotContextMenu = uicontextmenu(app.UIFigure);
            app.FitbothaxestodataMenu = uimenu(app.DataPlotContextMenu);
            app.FitbothaxestodataMenu.MenuSelectedFcn = createCallbackFcn(app, @FitbothaxestodataMenuSelected, true);
            app.FitbothaxestodataMenu.Text = 'Fit both axes to data';
            app.FitXaxistodataMenu = uimenu(app.DataPlotContextMenu);
            app.FitXaxistodataMenu.MenuSelectedFcn = createCallbackFcn(app, @FitXaxistodataMenuSelected, true);
            app.FitXaxistodataMenu.Text = 'Fit X axis to data';
            app.FitYaxistodataMenu = uimenu(app.DataPlotContextMenu);
            app.FitYaxistodataMenu.MenuSelectedFcn = createCallbackFcn(app, @FitYaxistodataMenuSelected, true);
            app.FitYaxistodataMenu.Text = 'Fit Y axis to data';
            % Assign app.DataPlotContextMenu
            app.DataAxes.ContextMenu = app.DataPlotContextMenu;
            app.DirectoryContextMenu = uicontextmenu(app.UIFigure);
            app.DirectoryContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @DirectoryContextMenuOpening, true);
            app.OpenfiledialogMenu = uimenu(app.DirectoryContextMenu);
            app.OpenfiledialogMenu.Text = 'Open file dialog';
            % Assign app.DirectoryContextMenu
            app.OutputfilepathEditField.ContextMenu = app.DirectoryContextMenu;
            app.ImportParametersContextMenu = uicontextmenu(app.UIFigure);
            app.GeneratetemplatejsonMenu = uimenu(app.ImportParametersContextMenu);
            app.GeneratetemplatejsonMenu.MenuSelectedFcn = createCallbackFcn(app, @GeneratetemplatejsonMenuSelected, true);
            app.GeneratetemplatejsonMenu.Text = 'Generate template json';
            % Assign app.ImportParametersContextMenu
            app.ImportparametersButton.ContextMenu = app.ImportParametersContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LQG_FP7_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end